classdef UBCFE_CreateTrack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        SaveDirectoryLocationLabel     matlab.ui.control.Label
        SaveDirectory                  matlab.ui.control.Button
        DesignedbyRyanChiricostaLabel  matlab.ui.control.Label
        CircuitMakerLabel              matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        FinishButton                   matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        DirectoryLocationLabel         matlab.ui.control.Label
        CircuitPhotoFolderButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        RightPanel                     matlab.ui.container.Panel
        TrackImage                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SelectedImage % Description
        StartFlag
        LastKey
        Cur_x
        Cur_y
        h1
        h2
        h
        X
        Y
        i
        TrackOutline
    end
    
    methods (Access = private)
        
        function UpdatePlot(app)
            
            app.LastKey;
            dCur = 1;


                switch app.LastKey
                    case 'leftarrow'       
                        app.Cur_x = app.Cur_x - dCur;
                    case 'rightarrow'
                        app.Cur_x = app.Cur_x + dCur;
                    case 'downarrow'
                        app.Cur_y = app.Cur_y + dCur;
                    case 'uparrow'
                        app.Cur_y = app.Cur_y - dCur;
                    case 'space'
                        app.X(app.i) = app.Cur_x;
                        app.Y(app.i) = app.Cur_y;
                        app.h1 = scatter(app.TrackImage,app.X(app.i),app.Y(app.i),'r');
                        app.i = app.i + 1;
                    case 'z'   
                        app.X(app.i-1) = NaN;
                        app.Y(app.i-1) = NaN;
                        delete(app.h1)
                        app.Cur_x = app.X(app.i-2);
                        app.Cur_y = app.Y(app.i-2);
                        app.i = app.i - 1;
                end
                
                delete(app.h2);
                app.h2 = scatter(app.TrackImage,app.Cur_x, app.Cur_y, 'r');


                xlim(app.TrackImage,[app.Cur_x-100, app.Cur_x+100]);
                ylim(app.TrackImage,[app.Cur_y-100, app.Cur_y+100]);
                
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              path = load('prevpath_CircuitPhoto.mat');
              path = path.path;
              app.DirectoryLocationLabel.Text=path;
              
              a=dir(path);                         % Obtains the contents of the selected path.
              b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
              b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
              app.UITable.Data=b;   
              
              path = load('prevpath_Save.mat');
              path = path.path;
              app.SaveDirectoryLocationLabel.Text=path;
              
              app.StartFlag = 0;          
               
        end

        % Button pushed function: CircuitPhotoFolderButton
        function CircuitPhotoFolderButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_CircuitPhoto.mat','path')
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.DirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

            a=dir(path);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.

            
            app.UITable.Data=b;    
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            FileName = char(app.UITable.Data(app.SelectedImage(1)));
            FileDir = append(app.DirectoryLocationLabel.Text,'/',FileName);
            
            hold(app.TrackImage,'off')
            
            app.h = imread(FileDir);  
            [app.Cur_y, app.Cur_x, ~] = size(app.h);
            
            image(app.TrackImage,app.h);
            axis(app.TrackImage,'equal');
            hold(app.TrackImage,'on');
            
            app.StartFlag = 1;
            app.i = 1;
         
            
            app.h2 = scatter(app.TrackImage,app.Cur_x, app.Cur_y, 'r');

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedImage = event.Indices;
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
                switch app.StartFlag
                    case 1
                    app.LastKey = event.Key;
                    UpdatePlot(app);
                    case 0
                end
            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
                app.LastKey = NaN;
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.StartFlag = 0;
            
            app.TrackOutline(:,1) = app.X;
            app.TrackOutline(:,2) = app.Y;
            
            app.TrackOutline = unique(app.TrackOutline,'rows','stable');          
            hold(app.TrackImage,'off');
            
            image(app.TrackImage,app.h);
            axis(app.TrackImage,'equal');
            hold(app.TrackImage,'on');
            
            plot(app.TrackImage,app.TrackOutline(:,1),app.TrackOutline(:,2),'r','LineWidth',3)


            %save("OutlineTrack1.mat","TrackOutline")
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            TrackSave = app.TrackOutline;
            
            %save('2018AutoCross_A.mat','TrackSave')
            
            if isempty(app.NameEditField.Value) == true
                disp('Error No File Name Entered');
            else
                SavedName = append(app.NameEditField.Value,'.mat');
                SavedDir = append(app.SaveDirectoryLocationLabel.Text,'\',SavedName);
                save(SavedDir,'TrackSave');
            end
        end

        % Button pushed function: SaveDirectory
        function SaveDirectoryButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_Save.mat','path')
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.SaveDirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {653, 653};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Circuit Data'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CircuitPhotoFolderButton = uibutton(app.LeftPanel, 'push');
            app.CircuitPhotoFolderButton.ButtonPushedFcn = createCallbackFcn(app, @CircuitPhotoFolderButtonPushed, true);
            app.CircuitPhotoFolderButton.Text = 'Circuit Data Folder';
            app.DirectoryLocationLabel = uilabel(app.LeftPanel);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.NameEditFieldLabel = uilabel(app.LeftPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LeftPanel, 'text');
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CircuitMakerLabel = uilabel(app.LeftPanel);
            app.CircuitMakerLabel.Text = 'Circuit Maker';
            app.DesignedbyRyanChiricostaLabel = uilabel(app.LeftPanel);
            app.DesignedbyRyanChiricostaLabel.Text = 'Designed by : Ryan Chiricosta';
            app.SaveDirectory = uibutton(app.LeftPanel, 'push');
            app.SaveDirectory.ButtonPushedFcn = createCallbackFcn(app, @SaveDirectoryButtonPushed, true);
            app.SaveDirectory.Text = 'Save Directory';
            app.SaveDirectoryLocationLabel = uilabel(app.LeftPanel);
            app.SaveDirectoryLocationLabel.Text = 'Save Directory Location';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Purpose : Use this app to plot out (x,y) coordinates of a track using an image of the track.  Once started need to click on figure to start moving around.'; ''; 'Arrow Key : Move target around '; 'Space : Save target point to track data'; 'Z : Undo''s last point (can''t do multiple sry)'; ''; 'Points must be selected in order, click finish when done selecting points.  Then save using save button.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TrackImage = uiaxes(app.RightPanel);
            title(app.TrackImage, 'Title')
            xlabel(app.TrackImage, 'X')
            ylabel(app.TrackImage, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UBCFE_CreateTrack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end