classdef UBCFE_RefineTrack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TrackLengthSpinner              matlab.ui.control.Spinner
        TrackDivisionsSpinner           matlab.ui.control.Spinner
        SplineFittingParamSpinner       matlab.ui.control.Spinner
        SplineFittingParamSpinnerLabel  matlab.ui.control.Label
        TrackDivisionsSlider            matlab.ui.control.Slider
        TrackDivisionsSliderLabel       matlab.ui.control.Label
        TrackLengthSlider               matlab.ui.control.Slider
        TrackLengthLabel                matlab.ui.control.Label
        OnOffCheckBox                   matlab.ui.control.CheckBox
        RadiusSmoothngSlider            matlab.ui.control.Slider
        RadiusSmoothngSliderLabel       matlab.ui.control.Label
        SaveDirectoryLocationLabel      matlab.ui.control.Label
        SaveDirectory                   matlab.ui.control.Button
        DesignedbyRyanChiricostaLabel   matlab.ui.control.Label
        CircuitMakerLabel               matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        DirectoryLocationLabel          matlab.ui.control.Label
        CircuitPhotoFolderButton        matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        LapLengthLabel                  matlab.ui.control.Label
        TrackLengthLabel_2              matlab.ui.control.Label
        AvgLengthLabel                  matlab.ui.control.Label
        AvgSegLengthLabel               matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        RadiusPlot                      matlab.ui.control.UIAxes
        TrackPlot                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SelectedCrse % Description
        LoadCrseData
        ModCrseData
        Mod2CrseData
        LapLength
    end
    
    methods (Access = private)
        
        function UpdatePlot(app)
            
           
                app.ModCrseData = app.LoadCrseData.*app.TrackLengthSlider.Value;


                %n = 5000;
                %bn = 0.005*n;
                
                t = 1:length(app.ModCrseData(:,1));
                xy = [transpose(app.ModCrseData(:,1));transpose(app.ModCrseData(:,2))];
                pp = csaps(t,xy,app.SplineFittingParamSpinner.Value);
                tInterp = linspace(1,length(app.ModCrseData(:,1)),app.TrackDivisionsSlider.Value);
                xyInterp = ppval(pp, tInterp);
                
                %figure
                
                plot(app.TrackPlot,app.LoadCrseData(:,1).*app.TrackLengthSlider.Value,app.LoadCrseData(:,2).*app.TrackLengthSlider.Value,'o:')
                hold(app.TrackPlot,'on')
                plot(app.TrackPlot,xyInterp(1,:),xyInterp(2,:),'r.')
                title(app.TrackPlot,'Track Map')
                legend(app.TrackPlot,{'Original data','Spline interpolation'},'FontSize',12)
                axis(app.TrackPlot,'equal');
                hold(app.TrackPlot,'off');
                
                app.ModCrseData = transpose(xyInterp);
                
                
                app.ModCrseData(1,3) = Inf;
                app.ModCrseData(1,4) = sectorL(app.ModCrseData(1,1), app.ModCrseData(1,2), app.ModCrseData(2,1), app.ModCrseData(2,2));
                app.ModCrseData(1,5) = 0;    % Cumulative distance
                
                
                for i = 2:(length(app.ModCrseData) - 1)                % Cycles through all course data and determines the radius and instantaneous center of each segment
                    
                    [R,xcyc] = fit_circle([app.ModCrseData(i-1,1) app.ModCrseData(i-1,2);app.ModCrseData(i,1) app.ModCrseData(i,2);app.ModCrseData(i+1,1) app.ModCrseData(i+1,2)]);
                    Slength = sectorL(app.ModCrseData(i,1), app.ModCrseData(i,2), app.ModCrseData(i+1,1), app.ModCrseData(i+1,2));   %Runs sector length function and writes to CrseData
                    
                    %     if R>200                                        %Sets a threshold where any radius above this is classified as a straight (ie. R=Inf)
                    %         CrseData(i,3) = Inf;
                    %     else
                    app.ModCrseData(i,3) = R;                          %Otherwise radius is set to calculated
                    %     end
                    
                    app.ModCrseData(i,4) = Slength;
                    app.ModCrseData(i,6:7) = xcyc;
                    app.ModCrseData(i+1,5) = app.ModCrseData(i,5) + Slength;
                    
                end
                
                app.Mod2CrseData = app.LoadCrseData;
                
                app.Mod2CrseData = app.Mod2CrseData*sum(app.ModCrseData(:,4))/sum(app.LoadCrseData(:,4));


%                 if app.OnOffCheckBox.Value == 1
%                     
%                     app.Mod2CrseData(:,3) = smooth(app.ModCrseData(:,3),app.RadiusSmoothngSlider.Value/100*app.TrackDivisionsSlider.Value);   %Smooths radius data based on moving aver with with bucket size of 3
%                     
%                 end


                plot(app.RadiusPlot,app.ModCrseData(:,5), app.ModCrseData(:,3),'b',app.Mod2CrseData(:,5),app.Mod2CrseData(:,3),'r');
                title(app.RadiusPlot,'Radius Plot');
                legend(app.RadiusPlot,'Processed','Raw');
                xlabel(app.RadiusPlot,'Distance (m)');
                ylabel(app.RadiusPlot,'Instantaneous Radius (m)');
                ylim(app.RadiusPlot,[0 200])                         
                
%                 for i = 2:(length(app.ModCrseData)-2)
%                     
%                     if app.Mod2CrseData(i,3) > 180
%                         app.Mod2CrseData(i,3) = Inf;
%                     end
%                     
%                 end


                %plot(app.TrackPlot,app.LoadCrseData(:,1), app.LoadCrseData(:,2),'b');


                  app.LapLength = sum(app.ModCrseData(:,4));
                  app.LapLengthLabel.Text = string(app.LapLength);
                  app.AvgLengthLabel.Text = string(mean(app.ModCrseData(:,4)));


%                 CrseData(1,7) = 0;                                           %Sets the values for direction vector and dtheta for the first segment
%                 CrseData(1,8) = 1;
%                 CrseData(1,9) = 0;
%                 
%                 
%                 
%                 
%                 
%                 
%                 for i = 2:length(CrseData)
%                     
%                     %Loops through the course data to determine the direction unit vector
%                     
%                     if CrseData(i,3) == inf                                  %if the radius is infinite (ie a straight) a different algorith for determining vector is used
%                         
%                         xvec = CrseData(i+1,1) - CrseData(i,1);              %Calculates x and y components of vector
%                         yvec = CrseData(i+1,2) - CrseData(i,2);
%                         mag = sqrt(yvec^2 + xvec^2);                         %Calculates magnitude so vector can be normalized
%                         CrseData(i,7) = xvec/mag;
%                         CrseData(i,8) = yvec/mag;
%                         
%                     else
%                         
%                         yvec = CrseData(i,2) - CrseData(i,6);               %Otherwise direction vector is the vector perpendicular to vector from center of point
%                         xvec = CrseData(i,1) - CrseData(i,5);
%                         mag = sqrt(yvec^2 + xvec^2);
%                         CrseData(i,7) = yvec/mag;
%                         CrseData(i,8) = -1*(xvec/mag);
%                         
%                     end
%                     if i ~= 1                                                               %Determines unit vector direction based off whether different in angle between previous is greater than 90
%                         
%                         dtheta = atan2d(CrseData(i-1,7)*CrseData(i,8) - CrseData(i-1,8)*CrseData(i,7), CrseData(i-1,7)*CrseData(i,7) + CrseData(i-1,8)*CrseData(i,8));
%                         
%                     end
%                     
%                     dtheta = abs(dtheta);
%                     
%                     if dtheta >= 90
%                         
%                         CrseData(i,7) = -1*(yvec/mag);
%                         CrseData(i,8) = xvec/mag;
%                         
%                     end
%                     
%                     CrseData(i,9) = deg2rad(atan2d(CrseData(i-1,7)*CrseData(i,8) - CrseData(i-1,8)*CrseData(i,7), CrseData(i-1,7)*CrseData(i,7) + CrseData(i-1,8)*CrseData(i,8)));
%                     
%                 end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              path = load('prevpath_CircuitPhoto.mat');
              path = path.path;
              app.DirectoryLocationLabel.Text=path;
              
              a=dir(path);                         % Obtains the contents of the selected path.
              b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
              b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
              app.UITable.Data=b;   
              
              path = load('prevpath_Save.mat');
              path = path.path;
              app.SaveDirectoryLocationLabel.Text=path;


        end

        % Button pushed function: CircuitPhotoFolderButton
        function CircuitPhotoFolderButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_CircuitPhoto.mat','path')
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.DirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

            a=dir(path);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.

            
            app.UITable.Data=b;    
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedCrse = event.Indices;
            CourseName = char(app.UITable.Data(app.SelectedCrse(1)))
            CourseDir = append(app.DirectoryLocationLabel.Text,'\',CourseName)
            Course = load(CourseDir);
            app.LoadCrseData = Course.TrackSave;
            
            app.LoadCrseData(1,3) = inf;
            app.LoadCrseData(1,4) = sectorL(app.LoadCrseData(1,1), app.LoadCrseData(1,2), app.LoadCrseData(2,1), app.LoadCrseData(2,2));
            app.LoadCrseData(1,5) = 0;    % Cumulative distance
            
             for i = 2:(length(app.LoadCrseData) - 1)                % Cycles through all course data and determines the radius and instantaneous center of each segment
                    
                    [R,xcyc] = fit_circle([app.LoadCrseData(i-1,1) app.LoadCrseData(i-1,2);app.LoadCrseData(i,1) app.LoadCrseData(i,2);app.LoadCrseData(i+1,1) app.LoadCrseData(i+1,2)]);
                    Slength = sectorL(app.LoadCrseData(i,1), app.LoadCrseData(i,2), app.LoadCrseData(i+1,1), app.LoadCrseData(i+1,2));   %Runs sector length function and writes to CrseData
                    
                    %     if R>200                                        %Sets a threshold where any radius above this is classified as a straight (ie. R=Inf)
                    %         CrseData(i,3) = Inf;
                    %     else
                    app.LoadCrseData(i,3) = R;                          %Otherwise radius is set to calculated
                    %     end
                    
                    app.LoadCrseData(i,4) = Slength;
                    app.LoadCrseData(i,6:7) = xcyc;
                    app.LoadCrseData(i+1,5) = app.LoadCrseData(i,5) + Slength;
                    
              end


            UpdatePlot(app);
            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            TrackSave = app.ModCrseData;
            
            %save('2018AutoCross_A.mat','TrackSave')
            
            if isempty(app.NameEditField.Value) == true
                disp('Error No File Name Entered');
            else
                SavedName = append(app.NameEditField.Value,'.mat');
                SavedDir = append(app.SaveDirectoryLocationLabel.Text,'\',SavedName);
                save(SavedDir,'TrackSave');
            end
        end

        % Button pushed function: SaveDirectory
        function SaveDirectoryButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_Save.mat','path')
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.SaveDirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

          
        end

        % Value changed function: TrackDivisionsSlider
        function TrackDivisionsSliderValueChanged(app, event)
            app.TrackDivisionsSpinner.Value = app.TrackDivisionsSlider.Value;
            UpdatePlot(app);
        end

        % Value changed function: TrackLengthSlider
        function TrackLengthSliderValueChanged(app, event)
            app.TrackLengthSpinner.Value = app.TrackLengthSlider.Value;
            UpdatePlot(app);
        end

        % Value changed function: RadiusSmoothngSlider
        function RadiusSmoothngSliderValueChanged(app, event)
            %value = app.RadiusSmoothngSlider.Value;
            UpdatePlot(app)
        end

        % Callback function
        function SplineFittingParamSliderValueChanged(app, event)
            %value = app.SplineFittingParamSlider.Value;
            UpdatePlot(app)
        end

        % Value changed function: SplineFittingParamSpinner
        function SplineFittingParamSpinnerValueChanged(app, event)
            %value = app.SplineFittingParamSpinner.Value;
            UpdatePlot(app)
        end

        % Value changed function: TrackDivisionsSpinner
        function TrackDivisionsSpinnerValueChanged(app, event)
            app.TrackDivisionsSlider.Value =  app.TrackDivisionsSpinner.Value;
            UpdatePlot(app)
            
        end

        % Value changed function: TrackLengthSpinner
        function TrackLengthSpinnerValueChanged(app, event)
            app.TrackLengthSlider.Value = app.TrackLengthSpinner.Value;
            UpdatePlot(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {735, 735};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {329, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Circuit Data'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CircuitPhotoFolderButton = uibutton(app.LeftPanel, 'push');
            app.CircuitPhotoFolderButton.ButtonPushedFcn = createCallbackFcn(app, @CircuitPhotoFolderButtonPushed, true);
            app.CircuitPhotoFolderButton.Text = 'Circuit Data Folder';
            app.DirectoryLocationLabel = uilabel(app.LeftPanel);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.NameEditFieldLabel = uilabel(app.LeftPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LeftPanel, 'text');
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CircuitMakerLabel = uilabel(app.LeftPanel);
            app.CircuitMakerLabel.Text = 'Circuit Maker';
            app.DesignedbyRyanChiricostaLabel = uilabel(app.LeftPanel);
            app.DesignedbyRyanChiricostaLabel.Text = 'Designed by : Ryan Chiricosta';
            app.SaveDirectory = uibutton(app.LeftPanel, 'push');
            app.SaveDirectory.ButtonPushedFcn = createCallbackFcn(app, @SaveDirectoryButtonPushed, true);
            app.SaveDirectory.Text = 'Save Directory';
            app.SaveDirectoryLocationLabel = uilabel(app.LeftPanel);
            app.SaveDirectoryLocationLabel.Text = 'Save Directory Location';
            app.RadiusSmoothngSliderLabel = uilabel(app.LeftPanel);
            app.RadiusSmoothngSliderLabel.Text = 'Radius Smoothng %';
            app.RadiusSmoothngSlider = uislider(app.LeftPanel);
            app.RadiusSmoothngSlider.Limits = [0 1];
            app.RadiusSmoothngSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSmoothngSliderValueChanged, true);
            app.OnOffCheckBox = uicheckbox(app.LeftPanel);
            app.OnOffCheckBox.Text = 'On/Off';
            app.TrackLengthLabel = uilabel(app.LeftPanel);
            app.TrackLengthLabel.Text = 'Track Length ';
            app.TrackLengthSlider = uislider(app.LeftPanel);
            app.TrackLengthSlider.Limits = [0.1 3];
            app.TrackLengthSlider.ValueChangedFcn = createCallbackFcn(app, @TrackLengthSliderValueChanged, true);
            app.TrackLengthSlider.Value = 0.1;
            app.TrackDivisionsSliderLabel = uilabel(app.LeftPanel);
            app.TrackDivisionsSliderLabel.Text = 'Track Divisions';
            app.TrackDivisionsSlider = uislider(app.LeftPanel);
            app.TrackDivisionsSlider.Limits = [100 9000];
            app.TrackDivisionsSlider.ValueChangedFcn = createCallbackFcn(app, @TrackDivisionsSliderValueChanged, true);
            app.TrackDivisionsSlider.Value = 1500;
            app.SplineFittingParamSpinnerLabel = uilabel(app.LeftPanel);
            app.SplineFittingParamSpinnerLabel.Text = 'Spline Fitting Param';
            app.SplineFittingParamSpinner = uispinner(app.LeftPanel);
            app.SplineFittingParamSpinner.Step = 0.001;
            app.SplineFittingParamSpinner.ValueChangedFcn = createCallbackFcn(app, @SplineFittingParamSpinnerValueChanged, true);
            app.SplineFittingParamSpinner.Value = 0.5;
            app.TrackDivisionsSpinner = uispinner(app.LeftPanel);
            app.TrackDivisionsSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackDivisionsSpinnerValueChanged, true);
            app.TrackLengthSpinner = uispinner(app.LeftPanel);
            app.TrackLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackLengthSpinnerValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.TrackPlot = uiaxes(app.RightPanel);
            title(app.TrackPlot, 'Title')
            xlabel(app.TrackPlot, 'X')
            ylabel(app.TrackPlot, 'Y')
            app.RadiusPlot = uiaxes(app.RightPanel);
            title(app.RadiusPlot, 'Title')
            xlabel(app.RadiusPlot, 'X')
            ylabel(app.RadiusPlot, 'Y')
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'Purpose : Use this app to plot out (x,y) coordinates of a track using an image of the track.  Once started need to click on figure to start moving around.'; ''; 'Arrow Key : Move target around '; 'Space : Save target point to track data'; 'Z : Undo''s last point (can''t do multiple sry)'; ''; 'Points must be selected in order, click finish when done selecting points.  Then save using save button.'};
            app.AvgSegLengthLabel = uilabel(app.RightPanel);
            app.AvgSegLengthLabel.Text = 'Avg. Seg Length :';
            app.AvgLengthLabel = uilabel(app.RightPanel);
            app.AvgLengthLabel.Text = '---m';
            app.TrackLengthLabel_2 = uilabel(app.RightPanel);
            app.TrackLengthLabel_2.Text = 'Track Length:';
            app.LapLengthLabel = uilabel(app.RightPanel);
            app.LapLengthLabel.Text = '------m';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UBCFE_RefineTrack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end