classdef Model_Dashboard_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AutocrossTab                    matlab.ui.container.Tab
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        meansnostopconditionLabel       matlab.ui.control.Label
        StopVelocityEditField           matlab.ui.control.NumericEditField
        StopVelocityEditFieldLabel      matlab.ui.control.Label
        StartVelocityEditField          matlab.ui.control.NumericEditField
        StartVelocityEditFieldLabel     matlab.ui.control.Label
        EnergyOutput_AutoCross          matlab.ui.control.Label
        EnergyLabel_AutoCross           matlab.ui.control.Label
        RunNameEditField_AutoCross      matlab.ui.control.EditField
        RunNameEditFieldLabel           matlab.ui.control.Label
        SaveDirectoryLocationLabel_AutoCross  matlab.ui.control.Label
        SaveDirectory_AutoCross         matlab.ui.control.Button
        SaveButton_AutoCross            matlab.ui.control.Button
        LapLengthOutput_AutoCross       matlab.ui.control.Label
        LapLengthLabel_AutpCross        matlab.ui.control.Label
        TimeOutput_AutoCross            matlab.ui.control.Label
        LapTimeLabel_AutoCross          matlab.ui.control.Label
        Plots_AutoCross                 matlab.ui.container.TabGroup
        VelocityTraceTab_AutoCross      matlab.ui.container.Tab
        BrakeTraceCheckBox              matlab.ui.control.CheckBox
        AccelTraceCheckBox              matlab.ui.control.CheckBox
        VelocityTrace_AutoCross         matlab.ui.control.UIAxes
        WheelForceTracesTab_AutoCross   matlab.ui.container.Tab
        FfxCheckBox_AutoCross           matlab.ui.control.CheckBox
        FfyCheckBox_AutoCross           matlab.ui.control.CheckBox
        FfzCheckBox_AutoCross           matlab.ui.control.CheckBox
        FrxCheckBox_AutoCross           matlab.ui.control.CheckBox
        FryCheckBox_AutoCross           matlab.ui.control.CheckBox
        FrzCheckBox_AutoCross           matlab.ui.control.CheckBox
        WheelTraces_AutoCross           matlab.ui.control.UIAxes
        PowertTracesTab_AutoCross       matlab.ui.container.Tab
        PowerTraces_AutoCross           matlab.ui.control.UIAxes
        DirectoryLocationLabel_AutoCross  matlab.ui.control.Label
        CircuitDataFolderButton_AutoCross  matlab.ui.control.Button
        UITable_AutoCross               matlab.ui.control.Table
        CompAutocross                   matlab.ui.control.Button
        AutocrossEventLabel             matlab.ui.control.Label
        CircuitPlotColored_AutoCross    matlab.ui.control.UIAxes
        EnduranceTab                    matlab.ui.container.Tab
        PowerDerateOutput_Endurance     matlab.ui.control.Label
        PowerDerateLabel_Endurance      matlab.ui.control.Label
        TotalLengthOutput_Endurance     matlab.ui.control.Label
        TotalLengthLabel_Endurance      matlab.ui.control.Label
        TotalTimeOutput_Endurance       matlab.ui.control.Label
        TotalTimeLabel_Endurance        matlab.ui.control.Label
        EnergyLapOutput_Endurance       matlab.ui.control.Label
        EnergyLap_Endurance             matlab.ui.control.Label
        EnergyTotalOutput_Endurance     matlab.ui.control.Label
        EnergyTotal_Endurance           matlab.ui.control.Label
        AvailableEnergykWhEditField     matlab.ui.control.NumericEditField
        AvailableEnergykWhLabel         matlab.ui.control.Label
        RunNameEditField_Endurance      matlab.ui.control.EditField
        RunNameEditField_2Label         matlab.ui.control.Label
        SaveDirectoryLocationLabel_Endurance  matlab.ui.control.Label
        SaveDirectory_Endurance         matlab.ui.control.Button
        SaveButton_Endurance            matlab.ui.control.Button
        LapLengthOutput_Endurance       matlab.ui.control.Label
        LapLengthLabel_Endurance        matlab.ui.control.Label
        LapTimeOutput_Endurance         matlab.ui.control.Label
        LapTimeLabel_Endurance          matlab.ui.control.Label
        EndurancePlots                  matlab.ui.container.TabGroup
        VelocityTraceTab_Endurance      matlab.ui.container.Tab
        VelocityTrace_Endurance         matlab.ui.control.UIAxes
        WheelForceTracesTab_Endurance   matlab.ui.container.Tab
        FfxCheckBox_Endurance           matlab.ui.control.CheckBox
        FfyCheckBox_Endurance           matlab.ui.control.CheckBox
        FfzCheckBox_Endurance           matlab.ui.control.CheckBox
        FrxCheckBox_Endurance           matlab.ui.control.CheckBox
        FryCheckBox_Endurance           matlab.ui.control.CheckBox
        FrzCheckBox_Endurance           matlab.ui.control.CheckBox
        WheelTraces_Endurance           matlab.ui.control.UIAxes
        PowertTracesTab_Endurance       matlab.ui.container.Tab
        PowerTraces_Endurance           matlab.ui.control.UIAxes
        DirectoryLocationLabel_Endurance  matlab.ui.control.Label
        CircuitDataFolderButton_Endurance  matlab.ui.control.Button
        UITable_Endurance               matlab.ui.control.Table
        CompEndurance                   matlab.ui.control.Button
        EnduranceEventLabel             matlab.ui.control.Label
        CircuitPlotColored_Endurance    matlab.ui.control.UIAxes
        AccelerationTab                 matlab.ui.container.Tab
        AccelerationDistance_Var        matlab.ui.control.NumericEditField
        TotalDIstanceLabel              matlab.ui.control.Label
        TimeInterval_Var                matlab.ui.control.NumericEditField
        TimeInvervalLabel               matlab.ui.control.Label
        SensitivityStudiesLabel_2       matlab.ui.control.Label
        RunNameEditField_Acceleration   matlab.ui.control.EditField
        RunNameEditFieldLabel_2         matlab.ui.control.Label
        SaveDirectoryLocationLabel_Acceleration  matlab.ui.control.Label
        SaveDirectory_Acceleration      matlab.ui.control.Button
        SaveButton_Acceleration         matlab.ui.control.Button
        R_Ratio                         matlab.ui.control.Label
        F_Ratio                         matlab.ui.control.Label
        RearRatioLabel                  matlab.ui.control.Label
        FrontRatioLabel                 matlab.ui.control.Label
        RunSensitivitiesSwitch          matlab.ui.control.Switch
        RunSensitivitiesSwitchLabel     matlab.ui.control.Label
        Mass_Var                        matlab.ui.control.NumericEditField
        MassLabel                       matlab.ui.control.Label
        CGHeight_Var                    matlab.ui.control.NumericEditField
        CGHeightLabel                   matlab.ui.control.Label
        Drag_Var                        matlab.ui.control.NumericEditField
        DragEditFieldLabel              matlab.ui.control.Label
        DownForce_Var                   matlab.ui.control.NumericEditField
        DownForceEditFieldLabel         matlab.ui.control.Label
        TireCf_Var                      matlab.ui.control.NumericEditField
        TireMuLabel                     matlab.ui.control.Label
        SensitivityStudiesLabel         matlab.ui.control.Label
        FourWheelAccel                  matlab.ui.control.Button
        AccelerationLabel               matlab.ui.control.Label
        AccelVelPlot                    matlab.ui.control.UIAxes
        AccelForces                     matlab.ui.control.UIAxes
        VehicleParamtersTab             matlab.ui.container.Tab
        text11_2                        matlab.ui.control.Label
        FourWheelDriveOption            matlab.ui.control.CheckBox
        RearLabel                       matlab.ui.control.Label
        FrontLabel                      matlab.ui.control.Label
        AvailableEnergy_inp             matlab.ui.control.EditField
        text50_2                        matlab.ui.control.Label
        text45                          matlab.ui.control.Label
        text44                          matlab.ui.control.Label
        CPy_inp                         matlab.ui.control.EditField
        CPx_inp                         matlab.ui.control.EditField
        CfdownRW_inp                    matlab.ui.control.EditField
        AfRW_inp                        matlab.ui.control.EditField
        CfdragRW_inp                    matlab.ui.control.EditField
        CfdownFW_inp                    matlab.ui.control.EditField
        AfFW_inp                        matlab.ui.control.EditField
        CfdragFW_inp                    matlab.ui.control.EditField
        Afbdy_inp                       matlab.ui.control.EditField
        CfdragBdy_inp                   matlab.ui.control.EditField
        text43                          matlab.ui.control.Label
        text42                          matlab.ui.control.Label
        text37                          matlab.ui.control.Label
        text32                          matlab.ui.control.Label
        text31                          matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        text28                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        AeroParam                       matlab.ui.control.Label
        BdyCfDrag                       matlab.ui.control.Label
        CarTrack_inp                    matlab.ui.control.EditField
        text47                          matlab.ui.control.Label
        MomentInertia_inp               matlab.ui.control.EditField
        text46                          matlab.ui.control.Label
        WheelBase_inp                   matlab.ui.control.EditField
        text38                          matlab.ui.control.Label
        text36                          matlab.ui.control.Label
        text35                          matlab.ui.control.Label
        CGy_inp                         matlab.ui.control.EditField
        CGx_inp                         matlab.ui.control.EditField
        text34                          matlab.ui.control.Label
        text12                          matlab.ui.control.Label
        Nratio_inp                      matlab.ui.control.EditField
        Rtire_inp                       matlab.ui.control.EditField
        Pmax_inp                        matlab.ui.control.EditField
        Tmax_inp                        matlab.ui.control.EditField
        text11                          matlab.ui.control.Label
        text10                          matlab.ui.control.Label
        text9                           matlab.ui.control.Label
        MechEff_inp                     matlab.ui.control.EditField
        text50                          matlab.ui.control.Label
        ResCf_inp                       matlab.ui.control.EditField
        text48                          matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        CarMass_inp                     matlab.ui.control.EditField
        TireCf_inp                      matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        CG_CPplot                       matlab.ui.control.UIAxes
        MotorandGeartrainTab            matlab.ui.container.Tab
        R_ConstantSpeed                 matlab.ui.control.NumericEditField
        ConstantSpeedEditFieldLabel_2   matlab.ui.control.Label
        R_ConstantTorque                matlab.ui.control.NumericEditField
        ConstantTorqueEditFieldLabel_2  matlab.ui.control.Label
        R_MaxSpeed                      matlab.ui.control.NumericEditField
        MaxSpeedEditFieldLabel_2        matlab.ui.control.Label
        FrontMotorLabel                 matlab.ui.control.Label
        RearMotorLabel                  matlab.ui.control.Label
        F_ConstantSpeed                 matlab.ui.control.NumericEditField
        ConstantSpeedEditFieldLabel     matlab.ui.control.Label
        F_ConstantTorque                matlab.ui.control.NumericEditField
        ConstantTorqueEditFieldLabel    matlab.ui.control.Label
        F_MaxSpeed                      matlab.ui.control.NumericEditField
        MaxSpeedEditFieldLabel          matlab.ui.control.Label
        RunParameterSweepTab            matlab.ui.container.Tab
        SweepPoints_inp                 matlab.ui.control.NumericEditField
        SweepPointsEditFieldLabel       matlab.ui.control.Label
        SweepStatus                     matlab.ui.control.Lamp
        SimulationStatusLabel           matlab.ui.control.Label
        RunNameEditField_Sweep          matlab.ui.control.EditField
        RunNameEditFieldLabel_3         matlab.ui.control.Label
        SaveDirectoryLocationLabel_Sweep  matlab.ui.control.Label
        SaveDirectory_Sweep             matlab.ui.control.Button
        CourseDataforSweepSimulationLabel  matlab.ui.control.Label
        UITable_Endurance_Sweep         matlab.ui.control.Table
        UITable_AutoCross_Sweep         matlab.ui.control.Table
        SweepRange_inp                  matlab.ui.control.NumericEditField
        SweepRangeLabel                 matlab.ui.control.Label
        RunParameterSweepButton         matlab.ui.control.Button
        ParamterDropDown                matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        SectorDataC_AC % Description
        ForceDataC_AC
        
        AccelSimResults
        
        SectorDataC_EN
        ForceDataC_EN
        
        SelectedCourse_AC
        SelectedCourse_AC_Sweep
        SelectedCourse_EN
        SelectedCourse_EN_Sweep
        CarParameters
        AeroParameters
        
        TotalT_AC
        TotalT_EN
        
        LapLength_AC 
        LapLength_EN
        
        CourseName_AC
        CourseName_EN
         % Description

        OS
    end
    
  
    methods (Access = public)
        
        function [CP, AP] = CompileVehicleParameters(app)    % Compiles all of the car and aero parameters into structs CP and AP
            
            
            CP.TireCf = str2double(app.TireCf_inp.Value);
            CP.CarMass =  str2double(app.CarMass_inp.Value);
            CP.Rtire =  str2double(app.Rtire_inp.Value);
            CP.Pmax =  str2double(app.Pmax_inp.Value);
            CP.Tmax =  str2double(app.Tmax_inp.Value);
            CP.Nratio =  str2double(app.Nratio_inp.Value);
            CP.CG =  [str2double(app.CGx_inp.Value), str2double(app.CGy_inp.Value)] ;
            CP.WheelBase = str2double(app.WheelBase_inp.Value);
            CP.I = str2double(app.MomentInertia_inp);
            CP.CarTrack = str2double(app.CarTrack_inp.Value);
            CP.ResCf = str2double(app.ResCf_inp.Value);
            CP.MechEff = str2double(app.MechEff_inp.Value);
            CP.AvailableEnergy = str2double(app.AvailableEnergy_inp.Value);
            
            AP.CfdragBdy = str2double(app.CfdragBdy_inp.Value);
            AP.Afbdy =  str2double(app.Afbdy_inp.Value);
            AP.CfdragFW =  str2double(app.CfdragFW_inp.Value);
            AP.CfdownFW =  str2double(app.CfdownFW_inp.Value);
            AP.AfFW =  str2double(app.AfFW_inp.Value);
            AP.CfdragRW =  str2double(app.CfdragRW_inp.Value);
            AP.CfdownRW =  str2double(app.CfdownRW_inp.Value);
            AP.AfRW =  str2double(app.AfRW_inp.Value);
            AP.CP =  [str2double(app.CPx_inp.Value), str2double(app.CPy_inp.Value)];
            
            
        end
        
        function PlotVelocityTraces_AutoCross(app)

            plot(app.VelocityTrace_AutoCross,0,0)
            hold(app.VelocityTrace_AutoCross,"on")

            if app.BrakeTraceCheckBox.Value == 1
                plot(app.VelocityTrace_AutoCross,app.SectorDataC_AC(:,3),app.SectorDataC_AC(:,10),'r--')
            end
            
            if app.AccelTraceCheckBox.Value == 1
                plot(app.VelocityTrace_AutoCross,app.SectorDataC_AC(:,3),app.SectorDataC_AC(:,9),'g--')
            end

            plot(app.VelocityTrace_AutoCross,app.SectorDataC_AC(:,3),app.SectorDataC_AC(:,1),'b-')

            hold(app.VelocityTrace_AutoCross,"off");

        end

        function PlotWheelTraces_AutoCross(app)  % Handles wheel trace plots
            
            plot(app.WheelTraces_AutoCross,0,0)   % Effectively clears the plot 
            hold(app.WheelTraces_AutoCross,"on")

            if app.FrzCheckBox_AutoCross.Value == 1           % Depending on check boxes plot corresponding traces 
                plot(app.WheelTraces_AutoCross,app.SectorDataC_AC(:,3),app.ForceDataC_AC(:,2),'b--')
            end
            
            if app.FrxCheckBox_AutoCross.Value == 1
                plot(app.WheelTraces_AutoCross,app.SectorDataC_AC(:,3),app.ForceDataC_AC(:,4),'g--')
            end
            
            if app.FryCheckBox_AutoCross.Value == 1
                plot(app.WheelTraces_AutoCross,app.SectorDataC_AC(:,3),app.ForceDataC_AC(:,6),'r--')
            end
            
            if app.FfzCheckBox_AutoCross.Value == 1
                plot(app.WheelTraces_AutoCross,app.SectorDataC_AC(:,3),app.ForceDataC_AC(:,1),'b-')    
            end
            
            if app.FfxCheckBox_AutoCross.Value == 1
                plot(app.WheelTraces_AutoCross,app.SectorDataC_AC(:,3),app.ForceDataC_AC(:,3),'g-')
            end
            
            if app.FfyCheckBox_AutoCross.Value == 1
                plot(app.WheelTraces_AutoCross,app.SectorDataC_AC(:,3),app.ForceDataC_AC(:,5),'r-')
            end
            
            %legend(app.WheelTraces_AutoCross,'nil','Frz','Frx','Fry','Ffz','Ffx','Ffy')
            
            hold(app.WheelTraces_AutoCross,"off");
        
        end
        
        function PlotWheelTraces_Endurance(app)  % Handles wheel trace plots
            
          
            plot(app.WheelTraces_Endurance,0,0)   % Effectively clears the plot 
            
            hold(app.WheelTraces_Endurance,"on")
            if app.FrzCheckBox_Endurance.Value == 1           % Depending on check boxes plot corresponding traces 
                plot(app.WheelTraces_Endurance,app.SectorDataC_EN(:,3),app.ForceDataC_EN(:,2),'b--')
            end
            
            if app.FrxCheckBox_Endurance.Value == 1
                plot(app.WheelTraces_Endurance,app.SectorDataC_EN(:,3),app.ForceDataC_EN(:,4),'g--')
            end
            
            if app.FryCheckBox_Endurance.Value == 1
                plot(app.WheelTraces_Endurance,app.SectorDataC_EN(:,3),app.ForceDataC_EN(:,6),'r--')
            end
            
            if app.FfzCheckBox_Endurance.Value == 1
                plot(app.WheelTraces_Endurance,app.SectorDataC_EN(:,3),app.ForceDataC_EN(:,1),'b-')    
            end
            
            if app.FfxCheckBox_Endurance.Value == 1
                plot(app.WheelTraces_Endurance,app.SectorDataC_EN(:,3),app.ForceDataC_EN(:,3),'g-')
            end
            
            if app.FfyCheckBox_Endurance.Value == 1
                plot(app.WheelTraces_Endurance,app.SectorDataC_EN(:,3),app.ForceDataC_EN(:,5),'r-')
            end
            
            %legend(app.WheelTraces_AutoCross,'nil','Frz','Frx','Fry','Ffz','Ffx','Ffy')
            
            hold(app.WheelTraces_Endurance,"off");
        
        end
         
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              screenSize = get(groot,'ScreenSize');    % Reads the current screen size
              screenWidth = screenSize(3);      
              screenHeight = screenSize(4);
              left = screenWidth*0.2;       % Sets the load position for the app to 20% from the left side and 20% from the bottom
              bottom = screenHeight*0.2;
              %width = screenWidth*0.6;
              %height = screenHeight*0.55;
              drawnow;
              app.figure1.Position(1:2) = [left bottom];     % Sets the app properties to these values
              
              path = load('prevpath_CourseData.mat');  % load the previous course data folder location
              path = path.path;
              app.DirectoryLocationLabel_AutoCross.Text=path;
              app.DirectoryLocationLabel_Endurance.Text=path;

              app.OS = detectOS; 
              
              a=dir(path);                         % Obtains the contents of the selected path.
              b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
              b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
            
              app.UITable_AutoCross.Data=b;  % Loads the folder data into the table  
              app.UITable_Endurance.Data=b;  
              app.UITable_AutoCross_Sweep.Data=b;
              app.UITable_Endurance_Sweep.Data=b;  


              path = load('prevpath_SaveDir.mat');   % Loads previous save data folder location
              path = path.path;
              app.SaveDirectoryLocationLabel_AutoCross.Text=path;
              app.SaveDirectoryLocationLabel_Endurance.Text=path;


              ParameterString = ["TireCf", "CarMass", "Rtire", "Pmax", "Tmax", "Nratio", "CGx", "CGz"];
              
              app.ParamterDropDown.Items = ParameterString;


        end

        % Button pushed function: FourWheelAccel
        function FourWheelAccelButtonPushed(app, event)
            [app.CarParameters,app.AeroParameters] = CompileVehicleParameters(app);
            
            RunVarFlag = app.RunSensitivitiesSwitch.Value;
            
            Var.TireCf = [1+app.TireCf_Var.Value/100,1,1-app.TireCf_Var.Value/100] ;
            Var.DownForce = [1+app.DownForce_Var.Value/100,1, 1-app.DownForce_Var.Value/100];
            Var.Drag = [1+app.Drag_Var.Value/100,1,1-app.Drag_Var.Value/100];
            Var.CGHeight = [1+app.CGHeight_Var.Value/100,1,1-app.CGHeight_Var.Value/100];
            Var.Mass = [1+app.Mass_Var.Value/100,1,1-app.Mass_Var.Value/100] ;
            
            %[app.AccelSimResults] = FourWheelAccelFun(CP,AP,Var, RunVarFlag); 

            dt = app.TimeInterval_Var.Value;
            totalDistance = app.AccelerationDistance_Var.Value; 
            
            [app.AccelSimResults] = AccelSim(app.CarParameters,app.AeroParameters, dt, totalDistance); 
            
            
            Ffz = app.AccelSimResults(:,1) ;  % Ffz
            Frz = app.AccelSimResults(:,2) ;  % Frz
            Ffx = app.AccelSimResults(:,3) ;  % Ffx
            Frx = app.AccelSimResults(:,4) ;  % Frx
            TracLimFfx = app.AccelSimResults(:,5) ;
            TracLimFrx = app.AccelSimResults(:,6) ;
            Tr = app.AccelSimResults(:,7) ;
            Tf = app.AccelSimResults(:,8) ;
            Pf = app.AccelSimResults(:,9) ;
            Pr = app.AccelSimResults(:,10) ;
            vel = app.AccelSimResults(:,11) ;
            pos = app.AccelSimResults(:,12);
            t = app.AccelSimResults(:,13);
         
            TracLimTr = TracLimFrx*app.CarParameters.Rtire;
            TracLimTf = TracLimFfx*app.CarParameters.Rtire;
            
            MaxPf = max(Pf);
            MaxPr = max(Pr);
            
            MaxTf = max(Tf);
            MaxTr = max(Tr);
            
            FrontRatio = MaxTf/app.F_ConstantTorque.Value;
            RearRatio = MaxTr/app.R_ConstantTorque.Value;
            
            app.F_Ratio.Text = string(FrontRatio);
            app.R_Ratio.Text = string(RearRatio);
            
            
            yyaxis(app.AccelForces,'left');
            plot(app.AccelForces,vel(:),Tr(:),'r-',vel(:),Tf(:),'g-', vel(:), TracLimTr(:),'r--', vel(:), TracLimTf(:),'g--')
            
            xlabel(app.AccelForces,'Velocity m/s')
            ylabel(app.AccelForces,'Torque Nm')
       
       
            yyaxis(app.AccelForces,'right');
            plot(app.AccelForces,vel(:),Pr(:),'r-.', vel(:), Pf(:),'g-.')
            ylabel(app.AccelForces,'Power W')
       
            legend(app.AccelForces,'Rear Torque','Front Torque','Rear Traction Limit','Front Traction Limit','Rear Wheel Power','Front Wheel Power','Location',"northwest")
            
            yyaxis(app.AccelForces,'left');
            plot(app.AccelVelPlot, t(:), vel(:))
            
          
        end

        % Button pushed function: CompAutocross
        function CompAutocrossButtonPushed(app, event)
            [app.CarParameters,app.AeroParameters] = CompileVehicleParameters(app);
            

            if app.OS == "macos"
                app.CourseName_AC = char(app.UITable_AutoCross.Data(app.SelectedCourse_AC(1)))
                CourseDir = append(app.DirectoryLocationLabel_AutoCross.Text,'/',app.CourseName_AC)
                Course = load(CourseDir);
                CourseData = Course.TrackSave;

            else

                app.CourseName_AC = char(app.UITable_AutoCross.Data(app.SelectedCourse_AC(1)))
                CourseDir = append(app.DirectoryLocationLabel_AutoCross.Text,'\',app.CourseName_AC)
                Course = load(CourseDir);
                CourseData = Course.TrackSave;
            
            end
            
            options.start_velocity   = app.StartVelocityEditField.Value;
            options.stop_velocity    = app.StopVelocityEditField.Value;

            options.four_wheel_drive = app.FourWheelDriveOption.Value;

            [app.SectorDataC_AC, app.ForceDataC_AC,app.TotalT_AC,app.LapLength_AC,EnergyUsed] = LapModel(app.CarParameters,app.AeroParameters,CourseData,options);
            
            PlotVelocityTraces_AutoCross(app);

            plot(app.PowerTraces_AutoCross,app.SectorDataC_AC(:,3),app.SectorDataC_AC(:,5),'r',app.SectorDataC_AC(:,3),app.SectorDataC_AC(:,6),'b')
            legend(app.PowerTraces_AutoCross,'Front Power','Rear Power')
            
            PlotWheelTraces_AutoCross(app);
            
            C = transpose(app.SectorDataC_AC(:,1));   % Transpose velocity column for colored track plot
            
            scatter(app.CircuitPlotColored_AutoCross,CourseData(1:length(app.SectorDataC_AC),1),CourseData(1:length(app.SectorDataC_AC),2),[],C,'fill')
            
            
            app.TimeOutput_AutoCross.Text = string(app.TotalT_AC);
            app.LapLengthOutput_AutoCross.Text = string(app.LapLength_AC);
               
            %EnergyUsed = (sum(app.SectorDataC_AC(:,7)) + sum(app.SectorDataC_AC(:,8)))/(1000*3600);
                        
            app.EnergyOutput_AutoCross.Text = append(string(EnergyUsed), 'kWh');
            
            % Plot data:
            mesh(app.CircuitPlotColored_AutoCross,[CourseData(1:length(app.SectorDataC_AC),1) CourseData(1:length(app.SectorDataC_AC),1)], [CourseData(1:length(app.SectorDataC_AC),2) CourseData(1:length(app.SectorDataC_AC),2)], [C(:) C(:)],'LineWidth',2);

            view(app.CircuitPlotColored_AutoCross,2);   % Default 2-D view
            colorbar(app.CircuitPlotColored_AutoCross);  % Add a colorbar
            axis(app.CircuitPlotColored_AutoCross,'equal')
               
        end

        % Button pushed function: CircuitDataFolderButton_AutoCross
        function CircuitDataFolderButton_AutoCrossPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_CourseData.mat','path')
            app.figure1.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.figure1.Visible = 'on';      
            app.DirectoryLocationLabel_AutoCross.Text=path;  % Sets the label text to be the selected path

            a=dir(path);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.

            
            app.UITable_AutoCross.Data=b;                  % Displays the directory information to the UITable.
        end

        % Value changed function: FrzCheckBox_AutoCross
        function FrzCheckBox_AutoCrossValueChanged(app, event)
            PlotWheelTraces_AutoCross(app);            
        end

        % Value changed function: FryCheckBox_AutoCross
        function FryCheckBox_AutoCrossValueChanged(app, event)
            PlotWheelTraces_AutoCross(app);     
            
        end

        % Value changed function: FrxCheckBox_AutoCross
        function FrxCheckBox_AutoCrossValueChanged(app, event)
            PlotWheelTraces_AutoCross(app);     
            
        end

        % Value changed function: FfzCheckBox_AutoCross
        function FfzCheckBox_AutoCrossValueChanged(app, event)
            PlotWheelTraces_AutoCross(app);     
            
        end

        % Value changed function: FfyCheckBox_AutoCross
        function FfyCheckBox_AutoCrossValueChanged(app, event)
            PlotWheelTraces_AutoCross(app);     
            
        end

        % Value changed function: FfxCheckBox_AutoCross
        function FfxCheckBox_AutoCrossValueChanged(app, event)
           PlotWheelTraces_AutoCross(app);     
            
        end

        % Cell selection callback: UITable_AutoCross
        function UITable_AutoCrossCellSelection(app, event)
            app.SelectedCourse_AC = event.Indices;
            
        end

        % Button pushed function: SaveDirectory_AutoCross
        function SaveDirectory_AutoCrossButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_SaveDir.mat','path')    
            app.figure1.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.figure1.Visible = 'on';
            app.SaveDirectoryLocationLabel_AutoCross.Text=path;  % Sets the label text to be the selected path
        end

        % Button pushed function: SaveButton_AutoCross
        function SaveButton_AutoCrossPushed(app, event)
            
            SectorDataCSave = app.SectorDataC_AC;
            ForceDataCSave = app.ForceDataC_AC;
            TotalTSave = app.TotalT_AC;
            LapLengthSave = app.LapLength_AC;
            CarParametersSave = app.CarParameters;
            AeroParametersSave = app.AeroParameters;
            CourseNameSave = app.CourseName_AC;
            

            if isempty(app.RunNameEditField_AutoCross.Value) == true
                disp('Error No File Name Entered');
            else

                if app.OS == "macos"
                    FolderDir = append(app.SaveDirectoryLocationLabel_AutoCross.Text,'/',app.RunNameEditField_AutoCross.Value);
                    mkdir(FolderDir);
                
                    SavedDir = append(app.SaveDirectoryLocationLabel_AutoCross.Text,'/',app.RunNameEditField_AutoCross.Value,'/');
                    save(append(SavedDir,app.RunNameEditField_AutoCross.Value,'.mat'),'SectorDataCSave','ForceDataCSave','TotalTSave','LapLengthSave','CarParametersSave','AeroParametersSave','CourseNameSave' );
                else
                    FolderDir = append(app.SaveDirectoryLocationLabel_AutoCross.Text,'\',app.RunNameEditField_AutoCross.Value);
                    mkdir(FolderDir);
                
                    SavedDir = append(app.SaveDirectoryLocationLabel_AutoCross.Text,'\',app.RunNameEditField_AutoCross.Value,'\');
                    save(append(SavedDir,app.RunNameEditField_AutoCross.Value,'.mat'),'SectorDataCSave','ForceDataCSave','TotalTSave','LapLengthSave','CarParametersSave','AeroParametersSave','CourseNameSave' );
                end 


            end   
        end

        % Button pushed function: CircuitDataFolderButton_Endurance
        function CircuitDataFolderButton_EndurancePushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_CourseData.mat','path')
            app.figure1.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.figure1.Visible = 'on';      
            app.DirectoryLocationLabel_Endurance.Text=path;  % Sets the label text to be the selected path

            a=dir(path);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.

            
            app.UITable_Endurance.Data=b;                  % Displays the directory information to the UITable.
        end

        % Button pushed function: SaveDirectory_Endurance
        function SaveDirectory_EnduranceButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_SaveDir.mat','path')    
            app.figure1.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.figure1.Visible = 'on';
            app.SaveDirectoryLocationLabel_Endurance.Text=path;  % Sets the label text to be the selected path
        end

        % Button pushed function: CompEndurance
        function CompEnduranceButtonPushed(app, event)
            [CP,AP] = CompileVehicleParameters(app);

            %Checks operating system to ensure proper call of save data

            if app.OS  == "macos"
                app.CourseName_EN = char(app.UITable_Endurance.Data(app.SelectedCourse_EN(1)));
                CourseDir = append(app.DirectoryLocationLabel_Endurance.Text,'/',app.CourseName_EN);
                Course = load(CourseDir);
                CourseData = Course.TrackSave;
           
            else

                app.CourseName_EN = char(app.UITable_Endurance.Data(app.SelectedCourse_EN(1)));
                CourseDir = append(app.DirectoryLocationLabel_Endurance.Text,'\',app.CourseName_EN);
                Course = load(CourseDir);
                CourseData = Course.TrackSave;

            end

            options.start_velocity   = app.StartVelocityEditField.Value;
            options.stop_velocity    = app.StopVelocityEditField.Value;
            options.four_wheel_drive = app.FourWheelDriveOption.Value;

            
            CP.AvailableEnergy = app.AvailableEnergykWhEditField.Value;
            
            [app.SectorDataC_EN, app.ForceDataC_EN,app.TotalT_EN,app.LapLength_EN, EnergyUsed_Lap, EnergyUsed_EN, ENScalar, CPpost, APpost] = runEndurance(CP,AP,CourseData,options);
            

            app.EnergyTotalOutput_Endurance.Text = append(string(EnergyUsed_EN),'kWh');
            app.EnergyLapOutput_Endurance.Text = append(string(EnergyUsed_Lap),'kWh');
            app.LapTimeOutput_Endurance.Text = append(string(app.TotalT_EN),'s');
            app.TotalTimeOutput_Endurance.Text = append(string(app.TotalT_EN*ENScalar),'s');
            app.LapLengthOutput_Endurance.Text = append(string(app.LapLength_EN),'m');
            app.TotalLengthOutput_Endurance.Text = append(string(app.LapLength_EN*ENScalar),'m');
            app.PowerDerateOutput_Endurance.Text = string(CPpost.Pmax/CP.Pmax);


            plot(app.VelocityTrace_Endurance,app.SectorDataC_EN(:,3),app.SectorDataC_EN(:,1))
            plot(app.PowerTraces_Endurance,app.SectorDataC_EN(:,3),app.SectorDataC_EN(:,5),'r',app.SectorDataC_EN(:,3),app.SectorDataC_EN(:,6),'b')
            legend(app.PowerTraces_Endurance,'Front Power','Rear Power')
            
            
            PlotWheelTraces_Endurance(app);
            
            C = transpose(app.SectorDataC_EN(:,1));   % Transpose velocity column for colored track plot
            
            scatter(app.CircuitPlotColored_Endurance,CourseData(1:length(app.SectorDataC_EN),1),CourseData(1:length(app.SectorDataC_EN),2),[],C,'fill')
          
            % Plot data:
            mesh(app.CircuitPlotColored_Endurance,[CourseData(1:length(app.SectorDataC_EN),1) CourseData(1:length(app.SectorDataC_EN),1)], [CourseData(1:length(app.SectorDataC_EN),2) CourseData(1:length(app.SectorDataC_EN),2)], [C(:) C(:)],'LineWidth',2);
            
            view(app.CircuitPlotColored_Endurance,2);   % Default 2-D view
            colorbar(app.CircuitPlotColored_Endurance);  % Add a colorbar
            axis(app.CircuitPlotColored_Endurance,'equal')
            
        end

        % Cell selection callback: UITable_Endurance
        function UITable_EnduranceCellSelection(app, event)
            app.SelectedCourse_EN = event.Indices;
            
        end

        % Value changed function: FrzCheckBox_Endurance
        function FrzCheckBox_EnduranceValueChanged(app, event)
            PlotWheelTraces_Endurance(app);
            
        end

        % Value changed function: FryCheckBox_Endurance
        function FryCheckBox_EnduranceValueChanged(app, event)
            
            PlotWheelTraces_Endurance(app);
        end

        % Value changed function: FrxCheckBox_Endurance
        function FrxCheckBox_EnduranceValueChanged(app, event)
     
            PlotWheelTraces_Endurance(app);
        end

        % Value changed function: FfzCheckBox_Endurance
        function FfzCheckBox_EnduranceValueChanged(app, event)
          
            PlotWheelTraces_Endurance(app);
        end

        % Value changed function: FfyCheckBox_Endurance
        function FfyCheckBox_EnduranceValueChanged(app, event)
           
            PlotWheelTraces_Endurance(app);
        end

        % Value changed function: FfxCheckBox_Endurance
        function FfxCheckBox_EnduranceValueChanged(app, event)
            
            PlotWheelTraces_Endurance(app);
        end

        % Button pushed function: SaveDirectory_Acceleration
        function SaveDirectory_AccelerationButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_SaveDir.mat','path')    
            app.figure1.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.figure1.Visible = 'on';
            app.SaveDirectoryLocationLabel_Acceleration.Text=path;  % Sets the label text to be the selected path
        end

        % Button pushed function: SaveButton_Acceleration
        function SaveButton_AccelerationPushed(app, event)
            
            
            AccelSimResultsSave = app.AccelSimResults;
            CarParametersSave = app.CarParameters;
            AeroParametersSave = app.AeroParameters;


            if isempty(app.RunNameEditField_Acceleration.Value) == true
                disp('Error No File Name Entered');
            else
                if app.OS == "macos"
                    FolderDir = append(app.SaveDirectoryLocationLabel_Acceleration.Text,'/',app.RunNameEditField_Acceleration.Value);
                    mkdir(FolderDir);
                
                    SavedDir = append(app.SaveDirectoryLocationLabel_Acceleration.Text,'/',app.RunNameEditField_Acceleration.Value,'/');
                    save(append(SavedDir,app.RunNameEditField_Acceleration.Value,'.mat'),'AccelSimResultsSave','CarParametersSave','AeroParametersSave');

                else
                    
                    FolderDir = append(app.SaveDirectoryLocationLabel_Acceleration.Text,'\',app.RunNameEditField_Acceleration.Value);
                    mkdir(FolderDir);
                
                    SavedDir = append(app.SaveDirectoryLocationLabel_Acceleration.Text,'\',app.RunNameEditField_Acceleration.Value,'\');
                    save(append(SavedDir,app.RunNameEditField_Acceleration.Value,'.mat'),'AccelSimResultsSave','CarParametersSave','AeroParametersSave');
                end 
               
            end
        end

        % Button pushed function: RunParameterSweepButton
        function RunParameterSweepButtonPushed(app, event)
            app.SweepStatus.Color = 'r';
            pause(0.1)
            SelectedParameter = app.ParamterDropDown.Value;
            SweepRange = app.SweepRange_inp.Value;
            SweepPoints = app.SweepPoints_inp.Value;


            if app.OS == "macos"

                CourseName_AC = char(app.UITable_AutoCross.Data(app.SelectedCourse_AC_Sweep(1)))
                CourseDir = append(app.DirectoryLocationLabel_AutoCross.Text,'/',CourseName_AC)
                Course = load(CourseDir);
                CourseData_AC = Course.TrackSave;
            
                CourseName_EN = char(app.UITable_Endurance.Data(app.SelectedCourse_EN_Sweep(1)))
                CourseDir = append(app.DirectoryLocationLabel_Endurance.Text,'/',CourseName_EN)
                Course = load(CourseDir);
                CourseData_EN = Course.TrackSave;


            else 
                CourseName_AC = char(app.UITable_AutoCross.Data(app.SelectedCourse_AC_Sweep(1)))
                CourseDir = append(app.DirectoryLocationLabel_AutoCross.Text,'\',CourseName_AC)
                Course = load(CourseDir);
                CourseData_AC = Course.TrackSave;
            
                CourseName_EN = char(app.UITable_Endurance.Data(app.SelectedCourse_EN_Sweep(1)))
                CourseDir = append(app.DirectoryLocationLabel_Endurance.Text,'\',CourseName_EN)
                Course = load(CourseDir);
                CourseData_EN = Course.TrackSave;
          
            end
            
      
            [CP, AP] = CompileVehicleParameters(app);


            runSweep(SelectedParameter, SweepRange, CP, AP, CourseData_AC, CourseData_EN, SweepPoints,app.SaveDirectoryLocationLabel_Sweep.Text,app.RunNameEditField_Sweep.Value);
            
            
            app.SweepStatus.Color = [0.9 0.9 0.9];
            
            
        end

        % Cell selection callback: UITable_AutoCross_Sweep
        function UITable_AutoCross_SweepCellSelection(app, event)
           app.SelectedCourse_AC_Sweep = event.Indices;
            
        end

        % Button pushed function: SaveDirectory_Sweep
        function SaveDirectory_SweepButtonPushed(app, event)
             path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            save('prevpath_SaveDir.mat','path')    
            app.figure1.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.figure1.Visible = 'on';
            app.SaveDirectoryLocationLabel_Sweep.Text=path;  % Sets the label text to be the selected path
        end

        % Cell selection callback: UITable_Endurance_Sweep
        function UITable_Endurance_SweepCellSelection(app, event)
            app.SelectedCourse_EN_Sweep = event.Indices;
            
        end

        % Button pushed function: SaveButton_Endurance
        function SaveButton_EndurancePushed(app, event)
            SectorDataCSave = app.SectorDataC_EN;
            ForceDataCSave = app.ForceDataC_EN;
            TotalTSave = app.TotalT_EN;
            LapLengthSave = app.LapLength_EN;
            CarParametersSave = app.CarParameters;
            AeroParametersSave = app.AeroParameters;
            CourseNameSave = app.CourseName_EN;
            

            if isempty(app.RunNameEditField_Endurance.Value) == true
                disp('Error No File Name Entered');
            else
                if app.OS == "macos"
                    FolderDir = append(app.SaveDirectoryLocationLabel_Endurance.Text,'/',app.RunNameEditField_Endurance.Value);
                    mkdir(FolderDir);
                
                    SavedDir = append(app.SaveDirectoryLocationLabel_Endurance.Text,'/',app.RunNameEditField_Endurance.Value,'/');
                    save(append(SavedDir,app.RunNameEditField_Endurance.Value,'.mat'),'SectorDataCSave','ForceDataCSave','TotalTSave','LapLengthSave','CarParametersSave','AeroParametersSave','CourseNameSave' );

                else

                    FolderDir = append(app.SaveDirectoryLocationLabel_Endurance.Text,'\',app.RunNameEditField_Endurance.Value);
                    mkdir(FolderDir);
                
                    SavedDir = append(app.SaveDirectoryLocationLabel_Endurance.Text,'\',app.RunNameEditField_Endurance.Value,'\');
                    save(append(SavedDir,app.RunNameEditField_Endurance.Value,'.mat'),'SectorDataCSave','ForceDataCSave','TotalTSave','LapLengthSave','CarParametersSave','AeroParametersSave','CourseNameSave' );

                end 

            end
        end

        % Value changed function: CGx_inp
        function CGx_inpValueChanged(app, event)
            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base'); 
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview"); 
            axis square; 
            hold off 
            legend
            
        end

        % Button down function: VehicleParamtersTab
        function VehicleParamtersTabButtonDown(app, event)
            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base'); 
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview"); 
            axis square; 
            hold off 
            legend 
        end

        % Value changed function: CGy_inp
        function CGy_inpValueChanged(app, event)
            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base'); 
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview"); 
            axis square; 
            hold off 
            legend 


        end

        % Value changed function: WheelBase_inp
        function WheelBase_inpValueChanged(app, event)

            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base');  
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview"); 
            axis square; 
            hold off 
            legend 


        end

        % Value changed function: CPx_inp
        function CPx_inpValueChanged(app, event)
            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base');  
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview"); 
            axis square; 
            hold off 
            legend 
            
        end

        % Value changed function: CPy_inp
        function CPy_inpValueChanged(app, event)
            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base'); 
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview"); 
            axis square; 
            hold off 
            legend 
            
        end

        % Value changed function: Rtire_inp
        function Rtire_inpValueChanged(app, event)
            cla(app.CG_CPplot, 'reset'); 
            
            r = str2double (app.Rtire_inp.Value); 
            WB = str2double( app.WheelBase_inp.Value);

            app.CG_CPplot
            hold on
            rearWheel = viscircles(app.CG_CPplot, [0 0], r, 'color', 'b');
            frontWheel = viscircles(app.CG_CPplot, [WB 0], r, 'color', 'b');
            connector = line([0 WB], [0 0],'color', 'b', 'LineWidth', 4,'DisplayName','Wheel Base');  
            CG = scatter(str2double( app.CGx_inp.Value ),str2double( app.CGy_inp.Value ),'r','o', 'filled','LineWidth',4, 'DisplayName','CG'); 
            CP = scatter(str2double( app.CPx_inp.Value ),str2double( app.CPy_inp.Value ),'g','o','filled','LineWidth',4, 'DisplayName','CP' );
            xlim ([-0.5 2]);
            ylim ([-0.5, 2]);
            xlabel("x (m)");
            ylabel("z (m)");
            title("Bicycle Model Preview")
            axis square; 
            hold off 
            legend 
            
        end

        % Value changed function: AccelTraceCheckBox
        function AccelTraceCheckBoxValueChanged(app, event)
            PlotVelocityTraces_AutoCross(app);
            
        end

        % Value changed function: BrakeTraceCheckBox
        function BrakeTraceCheckBoxValueChanged(app, event)
            PlotVelocityTraces_AutoCross(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'LapSimV2UI';
            app.figure1.Tag = 'figure1';
            app.TabGroup = uitabgroup(app.figure1);
            app.AutocrossTab = uitab(app.TabGroup);
            app.AutocrossTab.Title = 'Autocross';
            app.CircuitPlotColored_AutoCross = uiaxes(app.AutocrossTab);
            title(app.CircuitPlotColored_AutoCross, 'Track ')
            xlabel(app.CircuitPlotColored_AutoCross, 'X')
            ylabel(app.CircuitPlotColored_AutoCross, 'Y')
            app.CircuitPlotColored_AutoCross.XTickLabelRotationMode = 'manual';
            app.CircuitPlotColored_AutoCross.YTickLabelRotationMode = 'manual';
            app.CircuitPlotColored_AutoCross.ZTickLabelRotationMode = 'manual';
            app.AutocrossEventLabel = uilabel(app.AutocrossTab);
            app.AutocrossEventLabel.Text = 'Autocross Event';
            app.CompAutocross = uibutton(app.AutocrossTab, 'push');
            app.CompAutocross.ButtonPushedFcn = createCallbackFcn(app, @CompAutocrossButtonPushed, true);
            app.CompAutocross.Tag = 'RunSim';
            app.CompAutocross.Text = 'Run Autocross';
            app.UITable_AutoCross = uitable(app.AutocrossTab);
            app.UITable_AutoCross.ColumnName = {'Circuit Data'};
            app.UITable_AutoCross.RowName = {};
            app.UITable_AutoCross.CellSelectionCallback = createCallbackFcn(app, @UITable_AutoCrossCellSelection, true);
            app.CircuitDataFolderButton_AutoCross = uibutton(app.AutocrossTab, 'push');
            app.CircuitDataFolderButton_AutoCross.ButtonPushedFcn = createCallbackFcn(app, @CircuitDataFolderButton_AutoCrossPushed, true);
            app.CircuitDataFolderButton_AutoCross.Text = 'Circuit Data Folder';
            app.DirectoryLocationLabel_AutoCross = uilabel(app.AutocrossTab);
            app.DirectoryLocationLabel_AutoCross.Text = 'Directory Location';
            app.Plots_AutoCross = uitabgroup(app.AutocrossTab);
            app.VelocityTraceTab_AutoCross = uitab(app.Plots_AutoCross);
            app.VelocityTraceTab_AutoCross.Title = 'Velocity Trace';
            app.VelocityTrace_AutoCross = uiaxes(app.VelocityTraceTab_AutoCross);
            title(app.VelocityTrace_AutoCross, 'Velocity Trace')
            xlabel(app.VelocityTrace_AutoCross, 'Distance (m)')
            ylabel(app.VelocityTrace_AutoCross, 'Velocity (m/s)')
            app.VelocityTrace_AutoCross.XTickLabelRotationMode = 'manual';
            app.VelocityTrace_AutoCross.YTickLabelRotationMode = 'manual';
            app.VelocityTrace_AutoCross.ZTickLabelRotationMode = 'manual';
            app.AccelTraceCheckBox = uicheckbox(app.VelocityTraceTab_AutoCross);
            app.AccelTraceCheckBox.ValueChangedFcn = createCallbackFcn(app, @AccelTraceCheckBoxValueChanged, true);
            app.AccelTraceCheckBox.Text = 'Accel Trace';
            app.BrakeTraceCheckBox = uicheckbox(app.VelocityTraceTab_AutoCross);
            app.BrakeTraceCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrakeTraceCheckBoxValueChanged, true);
            app.BrakeTraceCheckBox.Text = 'Brake Trace';
            app.WheelForceTracesTab_AutoCross = uitab(app.Plots_AutoCross);
            app.WheelForceTracesTab_AutoCross.Title = 'Wheel Force Traces';
            app.WheelTraces_AutoCross = uiaxes(app.WheelForceTracesTab_AutoCross);
            title(app.WheelTraces_AutoCross, 'Title')
            xlabel(app.WheelTraces_AutoCross, 'X')
            ylabel(app.WheelTraces_AutoCross, 'Y')
            app.WheelTraces_AutoCross.XTickLabelRotationMode = 'manual';
            app.WheelTraces_AutoCross.YTickLabelRotationMode = 'manual';
            app.WheelTraces_AutoCross.ZTickLabelRotationMode = 'manual';
            app.FrzCheckBox_AutoCross = uicheckbox(app.WheelForceTracesTab_AutoCross);
            app.FrzCheckBox_AutoCross.ValueChangedFcn = createCallbackFcn(app, @FrzCheckBox_AutoCrossValueChanged, true);
            app.FrzCheckBox_AutoCross.Text = 'Frz - - -';
            app.FryCheckBox_AutoCross = uicheckbox(app.WheelForceTracesTab_AutoCross);
            app.FryCheckBox_AutoCross.ValueChangedFcn = createCallbackFcn(app, @FryCheckBox_AutoCrossValueChanged, true);
            app.FryCheckBox_AutoCross.Text = 'Fry - - -';
            app.FrxCheckBox_AutoCross = uicheckbox(app.WheelForceTracesTab_AutoCross);
            app.FrxCheckBox_AutoCross.ValueChangedFcn = createCallbackFcn(app, @FrxCheckBox_AutoCrossValueChanged, true);
            app.FrxCheckBox_AutoCross.Text = 'Frx - - -';
            app.FfzCheckBox_AutoCross = uicheckbox(app.WheelForceTracesTab_AutoCross);
            app.FfzCheckBox_AutoCross.ValueChangedFcn = createCallbackFcn(app, @FfzCheckBox_AutoCrossValueChanged, true);
            app.FfzCheckBox_AutoCross.Text = 'Ffz ---';
            app.FfyCheckBox_AutoCross = uicheckbox(app.WheelForceTracesTab_AutoCross);
            app.FfyCheckBox_AutoCross.ValueChangedFcn = createCallbackFcn(app, @FfyCheckBox_AutoCrossValueChanged, true);
            app.FfyCheckBox_AutoCross.Text = 'Ffy ---';
            app.FfxCheckBox_AutoCross = uicheckbox(app.WheelForceTracesTab_AutoCross);
            app.FfxCheckBox_AutoCross.ValueChangedFcn = createCallbackFcn(app, @FfxCheckBox_AutoCrossValueChanged, true);
            app.FfxCheckBox_AutoCross.Text = 'Ffx ---';
            app.PowertTracesTab_AutoCross = uitab(app.Plots_AutoCross);
            app.PowertTracesTab_AutoCross.Title = 'Powert Traces';
            app.PowerTraces_AutoCross = uiaxes(app.PowertTracesTab_AutoCross);
            title(app.PowerTraces_AutoCross, 'Power Trace')
            xlabel(app.PowerTraces_AutoCross, 'Distance (m)')
            ylabel(app.PowerTraces_AutoCross, 'Power Watts')
            app.PowerTraces_AutoCross.XTickLabelRotationMode = 'manual';
            app.PowerTraces_AutoCross.YTickLabelRotationMode = 'manual';
            app.PowerTraces_AutoCross.ZTickLabelRotationMode = 'manual';
            app.LapTimeLabel_AutoCross = uilabel(app.AutocrossTab);
            app.LapTimeLabel_AutoCross.Text = 'Lap Time : ';
            app.TimeOutput_AutoCross = uilabel(app.AutocrossTab);
            app.TimeOutput_AutoCross.Text = 'XXX secs';
            app.LapLengthLabel_AutpCross = uilabel(app.AutocrossTab);
            app.LapLengthLabel_AutpCross.Text = 'Lap Length : ';
            app.LapLengthOutput_AutoCross = uilabel(app.AutocrossTab);
            app.LapLengthOutput_AutoCross.Text = 'XXX m';
            app.SaveButton_AutoCross = uibutton(app.AutocrossTab, 'push');
            app.SaveButton_AutoCross.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_AutoCrossPushed, true);
            app.SaveButton_AutoCross.Text = 'Save';
            app.SaveDirectory_AutoCross = uibutton(app.AutocrossTab, 'push');
            app.SaveDirectory_AutoCross.ButtonPushedFcn = createCallbackFcn(app, @SaveDirectory_AutoCrossButtonPushed, true);
            app.SaveDirectory_AutoCross.Text = 'Save Directory';
            app.SaveDirectoryLocationLabel_AutoCross = uilabel(app.AutocrossTab);
            app.SaveDirectoryLocationLabel_AutoCross.Text = 'Save Directory Location';
            app.RunNameEditFieldLabel = uilabel(app.AutocrossTab);
            app.RunNameEditFieldLabel.Text = 'Run Name';
            app.RunNameEditField_AutoCross = uieditfield(app.AutocrossTab, 'text');
            app.EnergyLabel_AutoCross = uilabel(app.AutocrossTab);
            app.EnergyLabel_AutoCross.Text = 'Energy Used : ';
            app.EnergyOutput_AutoCross = uilabel(app.AutocrossTab);
            app.EnergyOutput_AutoCross.Text = 'XXX kWh';
            app.StartVelocityEditFieldLabel = uilabel(app.AutocrossTab);
            app.StartVelocityEditFieldLabel.Text = 'Start Velocity:';
            app.StartVelocityEditField = uieditfield(app.AutocrossTab, 'numeric');
            app.StartVelocityEditField.Limits = [0 1000];
            app.StopVelocityEditFieldLabel = uilabel(app.AutocrossTab);
            app.StopVelocityEditFieldLabel.Text = 'Stop Velocity:';
            app.StopVelocityEditField = uieditfield(app.AutocrossTab, 'numeric');
            app.StopVelocityEditField.Limits = [-1 1000];
            app.StopVelocityEditField.Value = -1;
            app.meansnostopconditionLabel = uilabel(app.AutocrossTab);
            app.meansnostopconditionLabel.Text = '(-1 means no stop condition)';
            app.msLabel = uilabel(app.AutocrossTab);
            app.msLabel.Text = '(m/s)';
            app.msLabel_2 = uilabel(app.AutocrossTab);
            app.msLabel_2.Text = '(m/s)';
            app.EnduranceTab = uitab(app.TabGroup);
            app.EnduranceTab.Title = 'Endurance';
            app.CircuitPlotColored_Endurance = uiaxes(app.EnduranceTab);
            title(app.CircuitPlotColored_Endurance, 'Track ')
            xlabel(app.CircuitPlotColored_Endurance, 'X')
            ylabel(app.CircuitPlotColored_Endurance, 'Y')
            app.CircuitPlotColored_Endurance.XTickLabelRotationMode = 'manual';
            app.CircuitPlotColored_Endurance.YTickLabelRotationMode = 'manual';
            app.CircuitPlotColored_Endurance.ZTickLabelRotationMode = 'manual';
            app.EnduranceEventLabel = uilabel(app.EnduranceTab);
            app.EnduranceEventLabel.Text = 'Endurance';
            app.CompEndurance = uibutton(app.EnduranceTab, 'push');
            app.CompEndurance.ButtonPushedFcn = createCallbackFcn(app, @CompEnduranceButtonPushed, true);
            app.CompEndurance.Text = 'Run Endurance';
            app.UITable_Endurance = uitable(app.EnduranceTab);
            app.UITable_Endurance.ColumnName = {'Circuit Data'};
            app.UITable_Endurance.RowName = {};
            app.UITable_Endurance.CellSelectionCallback = createCallbackFcn(app, @UITable_EnduranceCellSelection, true);
            app.CircuitDataFolderButton_Endurance = uibutton(app.EnduranceTab, 'push');
            app.CircuitDataFolderButton_Endurance.ButtonPushedFcn = createCallbackFcn(app, @CircuitDataFolderButton_EndurancePushed, true);
            app.CircuitDataFolderButton_Endurance.Text = 'Circuit Data Folder';
            app.DirectoryLocationLabel_Endurance = uilabel(app.EnduranceTab);
            app.DirectoryLocationLabel_Endurance.Text = 'Directory Location';
            app.EndurancePlots = uitabgroup(app.EnduranceTab);
            app.VelocityTraceTab_Endurance = uitab(app.EndurancePlots);
            app.VelocityTraceTab_Endurance.Title = 'Velocity Trace';
            app.VelocityTrace_Endurance = uiaxes(app.VelocityTraceTab_Endurance);
            title(app.VelocityTrace_Endurance, 'Velocity Trace')
            xlabel(app.VelocityTrace_Endurance, 'Distance (m)')
            ylabel(app.VelocityTrace_Endurance, 'Velocity (m/s)')
            app.VelocityTrace_Endurance.XTickLabelRotationMode = 'manual';
            app.VelocityTrace_Endurance.YTickLabelRotationMode = 'manual';
            app.VelocityTrace_Endurance.ZTickLabelRotationMode = 'manual';
            app.WheelForceTracesTab_Endurance = uitab(app.EndurancePlots);
            app.WheelForceTracesTab_Endurance.Title = 'Wheel Force Traces';
            app.WheelTraces_Endurance = uiaxes(app.WheelForceTracesTab_Endurance);
            title(app.WheelTraces_Endurance, 'Wheel Force Traces')
            xlabel(app.WheelTraces_Endurance, 'X')
            ylabel(app.WheelTraces_Endurance, 'Y')
            app.WheelTraces_Endurance.XTickLabelRotationMode = 'manual';
            app.WheelTraces_Endurance.YTickLabelRotationMode = 'manual';
            app.WheelTraces_Endurance.ZTickLabelRotationMode = 'manual';
            app.FrzCheckBox_Endurance = uicheckbox(app.WheelForceTracesTab_Endurance);
            app.FrzCheckBox_Endurance.ValueChangedFcn = createCallbackFcn(app, @FrzCheckBox_EnduranceValueChanged, true);
            app.FrzCheckBox_Endurance.Text = 'Frz - - -';
            app.FryCheckBox_Endurance = uicheckbox(app.WheelForceTracesTab_Endurance);
            app.FryCheckBox_Endurance.ValueChangedFcn = createCallbackFcn(app, @FryCheckBox_EnduranceValueChanged, true);
            app.FryCheckBox_Endurance.Text = 'Fry - - -';
            app.FrxCheckBox_Endurance = uicheckbox(app.WheelForceTracesTab_Endurance);
            app.FrxCheckBox_Endurance.ValueChangedFcn = createCallbackFcn(app, @FrxCheckBox_EnduranceValueChanged, true);
            app.FrxCheckBox_Endurance.Text = 'Frx - - -';
            app.FfzCheckBox_Endurance = uicheckbox(app.WheelForceTracesTab_Endurance);
            app.FfzCheckBox_Endurance.ValueChangedFcn = createCallbackFcn(app, @FfzCheckBox_EnduranceValueChanged, true);
            app.FfzCheckBox_Endurance.Text = 'Ffz ---';
            app.FfyCheckBox_Endurance = uicheckbox(app.WheelForceTracesTab_Endurance);
            app.FfyCheckBox_Endurance.ValueChangedFcn = createCallbackFcn(app, @FfyCheckBox_EnduranceValueChanged, true);
            app.FfyCheckBox_Endurance.Text = 'Ffy ---';
            app.FfxCheckBox_Endurance = uicheckbox(app.WheelForceTracesTab_Endurance);
            app.FfxCheckBox_Endurance.ValueChangedFcn = createCallbackFcn(app, @FfxCheckBox_EnduranceValueChanged, true);
            app.FfxCheckBox_Endurance.Text = 'Ffx ---';
            app.PowertTracesTab_Endurance = uitab(app.EndurancePlots);
            app.PowertTracesTab_Endurance.Title = 'Power Traces';
            app.PowerTraces_Endurance = uiaxes(app.PowertTracesTab_Endurance);
            title(app.PowerTraces_Endurance, 'Power Trace')
            xlabel(app.PowerTraces_Endurance, 'Distance (m)')
            ylabel(app.PowerTraces_Endurance, 'Power Watts')
            app.PowerTraces_Endurance.XTickLabelRotationMode = 'manual';
            app.PowerTraces_Endurance.YTickLabelRotationMode = 'manual';
            app.PowerTraces_Endurance.ZTickLabelRotationMode = 'manual';
            app.LapTimeLabel_Endurance = uilabel(app.EnduranceTab);
            app.LapTimeLabel_Endurance.Text = 'Lap Time : ';
            app.LapTimeOutput_Endurance = uilabel(app.EnduranceTab);
            app.LapTimeOutput_Endurance.Text = '--- secs';
            app.LapLengthLabel_Endurance = uilabel(app.EnduranceTab);
            app.LapLengthLabel_Endurance.Text = 'Lap Length : ';
            app.LapLengthOutput_Endurance = uilabel(app.EnduranceTab);
            app.LapLengthOutput_Endurance.Text = '--- m';
            app.SaveButton_Endurance = uibutton(app.EnduranceTab, 'push');
            app.SaveButton_Endurance.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_EndurancePushed, true);
            app.SaveButton_Endurance.Text = 'Save';
            app.SaveDirectory_Endurance = uibutton(app.EnduranceTab, 'push');
            app.SaveDirectory_Endurance.ButtonPushedFcn = createCallbackFcn(app, @SaveDirectory_EnduranceButtonPushed, true);
            app.SaveDirectory_Endurance.Text = 'Save Directory';
            app.SaveDirectoryLocationLabel_Endurance = uilabel(app.EnduranceTab);
            app.SaveDirectoryLocationLabel_Endurance.Text = 'Save Directory Location';
            app.RunNameEditField_2Label = uilabel(app.EnduranceTab);
            app.RunNameEditField_2Label.Text = 'Run Name';
            app.RunNameEditField_Endurance = uieditfield(app.EnduranceTab, 'text');
            app.AvailableEnergykWhLabel = uilabel(app.EnduranceTab);
            app.AvailableEnergykWhLabel.Text = 'Available Energy(kWh)';
            app.AvailableEnergykWhEditField = uieditfield(app.EnduranceTab, 'numeric');
            app.AvailableEnergykWhEditField.Value = 6.3;
            app.EnergyTotal_Endurance = uilabel(app.EnduranceTab);
            app.EnergyTotal_Endurance.Text = 'Energy Used Total :';
            app.EnergyTotalOutput_Endurance = uilabel(app.EnduranceTab);
            app.EnergyTotalOutput_Endurance.Text = '--- kWh';
            app.EnergyLap_Endurance = uilabel(app.EnduranceTab);
            app.EnergyLap_Endurance.Text = 'Energy Used / Lap:';
            app.EnergyLapOutput_Endurance = uilabel(app.EnduranceTab);
            app.EnergyLapOutput_Endurance.Text = '--- kWh';
            app.TotalTimeLabel_Endurance = uilabel(app.EnduranceTab);
            app.TotalTimeLabel_Endurance.Text = 'Total Time :';
            app.TotalTimeOutput_Endurance = uilabel(app.EnduranceTab);
            app.TotalTimeOutput_Endurance.Text = '--- secs';
            app.TotalLengthLabel_Endurance = uilabel(app.EnduranceTab);
            app.TotalLengthLabel_Endurance.Text = 'Total Length:';
            app.TotalLengthOutput_Endurance = uilabel(app.EnduranceTab);
            app.TotalLengthOutput_Endurance.Text = '--- m';
            app.PowerDerateLabel_Endurance = uilabel(app.EnduranceTab);
            app.PowerDerateLabel_Endurance.Text = 'Power Derate:';
            app.PowerDerateOutput_Endurance = uilabel(app.EnduranceTab);
            app.PowerDerateOutput_Endurance.Text = '----';
            app.AccelerationTab = uitab(app.TabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.AccelForces = uiaxes(app.AccelerationTab);
            title(app.AccelForces, 'Forces and Power')
            xlabel(app.AccelForces, 'X')
            ylabel(app.AccelForces, 'Y')
            app.AccelForces.XTickLabelRotationMode = 'manual';
            app.AccelForces.YTickLabelRotationMode = 'manual';
            app.AccelForces.ZTickLabelRotationMode = 'manual';
            app.AccelVelPlot = uiaxes(app.AccelerationTab);
            title(app.AccelVelPlot, 'Title')
            xlabel(app.AccelVelPlot, 'Time')
            ylabel(app.AccelVelPlot, 'Velocity (m/s)')
            app.AccelVelPlot.XTickLabelRotationMode = 'manual';
            app.AccelVelPlot.YTickLabelRotationMode = 'manual';
            app.AccelVelPlot.ZTickLabelRotationMode = 'manual';
            app.AccelerationLabel = uilabel(app.AccelerationTab);
            app.AccelerationLabel.Text = 'Acceleration ';
            app.FourWheelAccel = uibutton(app.AccelerationTab, 'push');
            app.FourWheelAccel.ButtonPushedFcn = createCallbackFcn(app, @FourWheelAccelButtonPushed, true);
            app.FourWheelAccel.Text = 'Run';
            app.SensitivityStudiesLabel = uilabel(app.AccelerationTab);
            app.SensitivityStudiesLabel.Text = 'Sensitivity Studies';
            app.TireMuLabel = uilabel(app.AccelerationTab);
            app.TireMuLabel.Text = 'Tire Mu  +/-%';
            app.TireCf_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.TireCf_Var.Value = 15;
            app.DownForceEditFieldLabel = uilabel(app.AccelerationTab);
            app.DownForceEditFieldLabel.Text = 'Down Force +/-%';
            app.DownForce_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.DownForce_Var.Value = 15;
            app.DragEditFieldLabel = uilabel(app.AccelerationTab);
            app.DragEditFieldLabel.Text = 'Drag +/-%';
            app.Drag_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.Drag_Var.Value = 15;
            app.CGHeightLabel = uilabel(app.AccelerationTab);
            app.CGHeightLabel.Text = 'CG Height +/-%';
            app.CGHeight_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.CGHeight_Var.Value = 15;
            app.MassLabel = uilabel(app.AccelerationTab);
            app.MassLabel.Text = 'Mass +/-%';
            app.Mass_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.Mass_Var.Value = 15;
            app.RunSensitivitiesSwitchLabel = uilabel(app.AccelerationTab);
            app.RunSensitivitiesSwitchLabel.Text = 'Run Sensitivities';
            app.RunSensitivitiesSwitch = uiswitch(app.AccelerationTab, 'slider');
            app.FrontRatioLabel = uilabel(app.AccelerationTab);
            app.FrontRatioLabel.Text = 'Front Ratio :';
            app.RearRatioLabel = uilabel(app.AccelerationTab);
            app.RearRatioLabel.Text = 'Rear Ratio :';
            app.F_Ratio = uilabel(app.AccelerationTab);
            app.F_Ratio.Text = '---';
            app.R_Ratio = uilabel(app.AccelerationTab);
            app.R_Ratio.Text = '---';
            app.SaveButton_Acceleration = uibutton(app.AccelerationTab, 'push');
            app.SaveButton_Acceleration.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_AccelerationPushed, true);
            app.SaveButton_Acceleration.Text = 'Save';
            app.SaveDirectory_Acceleration = uibutton(app.AccelerationTab, 'push');
            app.SaveDirectory_Acceleration.ButtonPushedFcn = createCallbackFcn(app, @SaveDirectory_AccelerationButtonPushed, true);
            app.SaveDirectory_Acceleration.Text = 'Save Directory';
            app.SaveDirectoryLocationLabel_Acceleration = uilabel(app.AccelerationTab);
            app.SaveDirectoryLocationLabel_Acceleration.Text = 'Save Directory Location';
            app.RunNameEditFieldLabel_2 = uilabel(app.AccelerationTab);
            app.RunNameEditFieldLabel_2.Text = 'Run Name';
            app.RunNameEditField_Acceleration = uieditfield(app.AccelerationTab, 'text');
            app.SensitivityStudiesLabel_2 = uilabel(app.AccelerationTab);
            app.SensitivityStudiesLabel_2.Text = 'Sensitivity Studies';
            app.TimeInvervalLabel = uilabel(app.AccelerationTab);
            app.TimeInvervalLabel.Text = 'Time Interval (s)';
            app.TimeInterval_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.TimeInterval_Var.Value = 0.001;
            app.TotalDIstanceLabel = uilabel(app.AccelerationTab);
            app.TotalDIstanceLabel.Text = 'Total Distance (m)';
            app.AccelerationDistance_Var = uieditfield(app.AccelerationTab, 'numeric');
            app.AccelerationDistance_Var.Value = 15;
            app.VehicleParamtersTab = uitab(app.TabGroup);
            app.VehicleParamtersTab.Title = 'Vehicle Paramters ';
            app.VehicleParamtersTab.ButtonDownFcn = createCallbackFcn(app, @VehicleParamtersTabButtonDown, true);
            app.CG_CPplot = uiaxes(app.VehicleParamtersTab);
            title(app.CG_CPplot, 'CP and CG')
            xlabel(app.CG_CPplot, 'x (m)')
            ylabel(app.CG_CPplot, 'z (m)')
            app.CG_CPplot.XLim = [-0.5 2];
            app.CG_CPplot.YLim = [-0.5 2];
            app.CG_CPplot.XTickLabelRotationMode = 'manual';
            app.CG_CPplot.YTickLabelRotationMode = 'manual';
            app.CG_CPplot.ZTickLabelRotationMode = 'manual';
            app.text3 = uilabel(app.VehicleParamtersTab);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Vehicle Parameters';
            app.TireCf_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.TireCf_inp.Tag = 'TireCf_inp';
            app.TireCf_inp.Value = '1.2';
            app.CarMass_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CarMass_inp.Tag = 'CarMass_inp';
            app.CarMass_inp.Value = '300';
            app.text4 = uilabel(app.VehicleParamtersTab);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tire Coefficient of Friction ';
            app.text8 = uilabel(app.VehicleParamtersTab);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Car Mass (kg)'; ''};
            app.text48 = uilabel(app.VehicleParamtersTab);
            app.text48.Tag = 'text48';
            app.text48.Text = {'Resistance Coeff.'; ''};
            app.ResCf_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.ResCf_inp.Tag = 'ResCf_inp';
            app.ResCf_inp.Value = '1.1';
            app.text50 = uilabel(app.VehicleParamtersTab);
            app.text50.Tag = 'text50';
            app.text50.Text = {'Mechanical Efficiency'; ''};
            app.MechEff_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.MechEff_inp.Tag = 'MechEff_inp';
            app.MechEff_inp.Value = '0.78';
            app.text9 = uilabel(app.VehicleParamtersTab);
            app.text9.Tag = 'text9';
            app.text9.Text = {'Tire Radius (m)'; ''};
            app.text10 = uilabel(app.VehicleParamtersTab);
            app.text10.Tag = 'text10';
            app.text10.Text = {'Max Power (W)'; ''};
            app.text11 = uilabel(app.VehicleParamtersTab);
            app.text11.Tag = 'text11';
            app.text11.Text = {'Max Torque (N/m)'; ''};
            app.Tmax_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.Tmax_inp.Tag = 'Tmax_inp';
            app.Tmax_inp.Value = '2000';
            app.Pmax_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.Pmax_inp.Tag = 'Pmax_inp';
            app.Pmax_inp.Value = '80000';
            app.Rtire_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.Rtire_inp.ValueChangedFcn = createCallbackFcn(app, @Rtire_inpValueChanged, true);
            app.Rtire_inp.Tag = 'Rtire_inp';
            app.Rtire_inp.Value = '0.2286';
            app.Nratio_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.Nratio_inp.Tag = 'Nratio_inp';
            app.Nratio_inp.Value = '12';
            app.text12 = uilabel(app.VehicleParamtersTab);
            app.text12.Tag = 'text12';
            app.text12.Text = {'Gear Ratio'; ''};
            app.text34 = uilabel(app.VehicleParamtersTab);
            app.text34.Tag = 'text34';
            app.text34.Text = {'Center of Gravity (x,y)'; ''};
            app.CGx_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CGx_inp.ValueChangedFcn = createCallbackFcn(app, @CGx_inpValueChanged, true);
            app.CGx_inp.Tag = 'CGx_inp';
            app.CGx_inp.Value = '0.7';
            app.CGy_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CGy_inp.ValueChangedFcn = createCallbackFcn(app, @CGy_inpValueChanged, true);
            app.CGy_inp.Tag = 'CGy_inp';
            app.CGy_inp.Value = '0.3';
            app.text35 = uilabel(app.VehicleParamtersTab);
            app.text35.Tag = 'text35';
            app.text35.Text = {'('; ''};
            app.text36 = uilabel(app.VehicleParamtersTab);
            app.text36.Tag = 'text36';
            app.text36.Text = {')'; ''};
            app.text38 = uilabel(app.VehicleParamtersTab);
            app.text38.Tag = 'text38';
            app.text38.Text = {'Wheel Base (m)'; ''};
            app.WheelBase_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.WheelBase_inp.ValueChangedFcn = createCallbackFcn(app, @WheelBase_inpValueChanged, true);
            app.WheelBase_inp.Tag = 'WheelBase_inp';
            app.WheelBase_inp.Value = '1.525';
            app.text46 = uilabel(app.VehicleParamtersTab);
            app.text46.Tag = 'text46';
            app.text46.Text = {'Car Moment of Inertia (I)'; ''};
            app.MomentInertia_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.MomentInertia_inp.Tag = 'MomentInertia_inp';
            app.MomentInertia_inp.Value = '2';
            app.text47 = uilabel(app.VehicleParamtersTab);
            app.text47.Tag = 'text47';
            app.text47.Text = {'Car Track (m)'; ''};
            app.CarTrack_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CarTrack_inp.Tag = 'CarTrack_inp';
            app.CarTrack_inp.Value = '1.525';
            app.BdyCfDrag = uilabel(app.VehicleParamtersTab);
            app.BdyCfDrag.Tag = 'BdyCfDrag';
            app.BdyCfDrag.Text = {'Coefficient of Drag '; ''};
            app.AeroParam = uilabel(app.VehicleParamtersTab);
            app.AeroParam.Tag = 'AeroParam';
            app.AeroParam.Text = 'Aero Parameters';
            app.text22 = uilabel(app.VehicleParamtersTab);
            app.text22.Tag = 'text22';
            app.text22.Text = {'Frontal Area Body (m^2)'; ''};
            app.text23 = uilabel(app.VehicleParamtersTab);
            app.text23.Tag = 'text23';
            app.text23.Text = {'Front Wing'; ''};
            app.text24 = uilabel(app.VehicleParamtersTab);
            app.text24.Tag = 'text24';
            app.text24.Text = {'Coefficient of Drag '; ''};
            app.text25 = uilabel(app.VehicleParamtersTab);
            app.text25.Tag = 'text25';
            app.text25.Text = {'Frontal Area '; ''};
            app.text26 = uilabel(app.VehicleParamtersTab);
            app.text26.Tag = 'text26';
            app.text26.Text = {'Coefficient of Downforce'; ''};
            app.text28 = uilabel(app.VehicleParamtersTab);
            app.text28.Tag = 'text28';
            app.text28.Text = {'Rear Wing'; ''};
            app.text30 = uilabel(app.VehicleParamtersTab);
            app.text30.Tag = 'text30';
            app.text30.Text = {'Coefficient of Drag '; ''};
            app.text31 = uilabel(app.VehicleParamtersTab);
            app.text31.Tag = 'text31';
            app.text31.Text = {'Frontal Area'; ''};
            app.text32 = uilabel(app.VehicleParamtersTab);
            app.text32.Tag = 'text32';
            app.text32.Text = {'Coefficient of Downforce'; ''};
            app.text37 = uilabel(app.VehicleParamtersTab);
            app.text37.Tag = 'text37';
            app.text37.Text = {','; ''};
            app.text42 = uilabel(app.VehicleParamtersTab);
            app.text42.Tag = 'text42';
            app.text42.Text = {'Center of Pressure (x,y)'; ''};
            app.text43 = uilabel(app.VehicleParamtersTab);
            app.text43.Tag = 'text43';
            app.text43.Text = {'('; ''};
            app.CfdragBdy_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CfdragBdy_inp.Tag = 'CfdragBdy_inp';
            app.CfdragBdy_inp.Value = '1';
            app.Afbdy_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.Afbdy_inp.Tag = 'Afbdy_inp';
            app.Afbdy_inp.Value = '0.8';
            app.CfdragFW_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CfdragFW_inp.Tag = 'CfdragFW_inp';
            app.CfdragFW_inp.Value = '1.2';
            app.AfFW_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.AfFW_inp.Tag = 'AfFW_inp';
            app.AfFW_inp.Value = '0.375';
            app.CfdownFW_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CfdownFW_inp.Tag = 'CfdownFW_inp';
            app.CfdownFW_inp.Value = '3';
            app.CfdragRW_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CfdragRW_inp.Tag = 'CfdragRW_inp';
            app.CfdragRW_inp.Value = '1.8';
            app.AfRW_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.AfRW_inp.Tag = 'AfRW_inp';
            app.AfRW_inp.Value = '0.8';
            app.CfdownRW_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CfdownRW_inp.Tag = 'CfdownRW_inp';
            app.CfdownRW_inp.Value = '3';
            app.CPx_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CPx_inp.ValueChangedFcn = createCallbackFcn(app, @CPx_inpValueChanged, true);
            app.CPx_inp.Tag = 'CPx_inp';
            app.CPx_inp.Value = '0.7';
            app.CPy_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.CPy_inp.ValueChangedFcn = createCallbackFcn(app, @CPy_inpValueChanged, true);
            app.CPy_inp.Tag = 'CPy_inp';
            app.CPy_inp.Value = '0.4';
            app.text44 = uilabel(app.VehicleParamtersTab);
            app.text44.Tag = 'text44';
            app.text44.Text = {')'; ''};
            app.text45 = uilabel(app.VehicleParamtersTab);
            app.text45.Tag = 'text45';
            app.text45.Text = {','; ''};
            app.text50_2 = uilabel(app.VehicleParamtersTab);
            app.text50_2.Tag = 'text50';
            app.text50_2.Text = 'Available Energy';
            app.AvailableEnergy_inp = uieditfield(app.VehicleParamtersTab, 'text');
            app.AvailableEnergy_inp.Tag = 'MechEff_inp';
            app.AvailableEnergy_inp.Value = '6.3';
            app.FrontLabel = uilabel(app.VehicleParamtersTab);
            app.FrontLabel.Text = 'Front';
            app.RearLabel = uilabel(app.VehicleParamtersTab);
            app.RearLabel.Text = 'Rear';
            app.FourWheelDriveOption = uicheckbox(app.VehicleParamtersTab);
            app.FourWheelDriveOption.Text = '';
            app.text11_2 = uilabel(app.VehicleParamtersTab);
            app.text11_2.Text = {'4-Wheel Drive'; ''};
            app.MotorandGeartrainTab = uitab(app.TabGroup);
            app.MotorandGeartrainTab.Title = 'Motor and Geartrain';
            app.MaxSpeedEditFieldLabel = uilabel(app.MotorandGeartrainTab);
            app.MaxSpeedEditFieldLabel.Text = 'Max Speed';
            app.F_MaxSpeed = uieditfield(app.MotorandGeartrainTab, 'numeric');
            app.ConstantTorqueEditFieldLabel = uilabel(app.MotorandGeartrainTab);
            app.ConstantTorqueEditFieldLabel.Text = 'Constant Torque';
            app.F_ConstantTorque = uieditfield(app.MotorandGeartrainTab, 'numeric');
            app.ConstantSpeedEditFieldLabel = uilabel(app.MotorandGeartrainTab);
            app.ConstantSpeedEditFieldLabel.Text = 'Constant Speed';
            app.F_ConstantSpeed = uieditfield(app.MotorandGeartrainTab, 'numeric');
            app.RearMotorLabel = uilabel(app.MotorandGeartrainTab);
            app.RearMotorLabel.Text = 'Rear Motor';
            app.FrontMotorLabel = uilabel(app.MotorandGeartrainTab);
            app.FrontMotorLabel.Text = 'Front Motor';
            app.MaxSpeedEditFieldLabel_2 = uilabel(app.MotorandGeartrainTab);
            app.MaxSpeedEditFieldLabel_2.Text = 'Max Speed';
            app.R_MaxSpeed = uieditfield(app.MotorandGeartrainTab, 'numeric');
            app.ConstantTorqueEditFieldLabel_2 = uilabel(app.MotorandGeartrainTab);
            app.ConstantTorqueEditFieldLabel_2.Text = 'Constant Torque';
            app.R_ConstantTorque = uieditfield(app.MotorandGeartrainTab, 'numeric');
            app.ConstantSpeedEditFieldLabel_2 = uilabel(app.MotorandGeartrainTab);
            app.ConstantSpeedEditFieldLabel_2.Text = 'Constant Speed';
            app.R_ConstantSpeed = uieditfield(app.MotorandGeartrainTab, 'numeric');
            app.RunParameterSweepTab = uitab(app.TabGroup);
            app.RunParameterSweepTab.Title = 'Run Parameter Sweep';
            app.DropDownLabel = uilabel(app.RunParameterSweepTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.ParamterDropDown = uidropdown(app.RunParameterSweepTab);
            app.RunParameterSweepButton = uibutton(app.RunParameterSweepTab, 'push');
            app.RunParameterSweepButton.ButtonPushedFcn = createCallbackFcn(app, @RunParameterSweepButtonPushed, true);
            app.RunParameterSweepButton.Text = {'Run Parameter Sweep'; ''};
            app.SweepRangeLabel = uilabel(app.RunParameterSweepTab);
            app.SweepRangeLabel.Text = 'Sweep Range (%)';
            app.SweepRange_inp = uieditfield(app.RunParameterSweepTab, 'numeric');
            app.UITable_AutoCross_Sweep = uitable(app.RunParameterSweepTab);
            app.UITable_AutoCross_Sweep.ColumnName = {'AutoCross - Circuit Data'};
            app.UITable_AutoCross_Sweep.RowName = {};
            app.UITable_AutoCross_Sweep.CellSelectionCallback = createCallbackFcn(app, @UITable_AutoCross_SweepCellSelection, true);
            app.UITable_Endurance_Sweep = uitable(app.RunParameterSweepTab);
            app.UITable_Endurance_Sweep.ColumnName = {'Endurance - Circuit Data'};
            app.UITable_Endurance_Sweep.RowName = {};
            app.UITable_Endurance_Sweep.CellSelectionCallback = createCallbackFcn(app, @UITable_Endurance_SweepCellSelection, true);
            app.CourseDataforSweepSimulationLabel = uilabel(app.RunParameterSweepTab);
            app.CourseDataforSweepSimulationLabel.Text = 'Course Data for Sweep Simulation';
            app.SaveDirectory_Sweep = uibutton(app.RunParameterSweepTab, 'push');
            app.SaveDirectory_Sweep.ButtonPushedFcn = createCallbackFcn(app, @SaveDirectory_SweepButtonPushed, true);
            app.SaveDirectory_Sweep.Text = 'Save Directory';
            app.SaveDirectoryLocationLabel_Sweep = uilabel(app.RunParameterSweepTab);
            app.SaveDirectoryLocationLabel_Sweep.Text = 'Save Directory Location';
            app.RunNameEditFieldLabel_3 = uilabel(app.RunParameterSweepTab);
            app.RunNameEditFieldLabel_3.Text = 'Run Name';
            app.RunNameEditField_Sweep = uieditfield(app.RunParameterSweepTab, 'text');
            app.SimulationStatusLabel = uilabel(app.RunParameterSweepTab);
            app.SimulationStatusLabel.Text = 'Simulation Status';
            app.SweepStatus = uilamp(app.RunParameterSweepTab);
            app.SweepPointsEditFieldLabel = uilabel(app.RunParameterSweepTab);
            app.SweepPointsEditFieldLabel.Text = 'Sweep Points';
            app.SweepPoints_inp = uieditfield(app.RunParameterSweepTab, 'numeric');
            app.SweepPoints_inp.Value = 10;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Dashboard_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end